classdef PHASE_StaMPS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TSPointsTab                     matlab.ui.container.Tab
        OpenTSPickerButton              matlab.ui.control.Button
        UIFigure                        matlab.ui.Figure
        StaMPSPanel                     matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        PreparationTab                  matlab.ui.container.Tab
        Image2                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        BrowseButton_2                  matlab.ui.control.Button
        BrowseButton                    matlab.ui.control.Button
        AmplitudethresholdLabel         matlab.ui.control.Label
        ValueEditField                  matlab.ui.control.NumericEditField
        ValueEditFieldLabel             matlab.ui.control.Label
        MasterdateDatePicker            matlab.ui.control.DatePicker
        MasterdateDatePicker_2Label     matlab.ui.control.Label
        PathEditField_3                 matlab.ui.control.EditField
        PathEditField_3Label            matlab.ui.control.Label
        ProjectfolderLabel              matlab.ui.control.Label
        PathEditField_2                 matlab.ui.control.EditField
        PathEditField_2Label            matlab.ui.control.Label
        StaMPSinstallationfolderLabel   matlab.ui.control.Label
        datapreparationforStaMPSCheckBox  matlab.ui.control.CheckBox
        GlobalVariablesTab              matlab.ui.container.Tab
        Image2_2                        matlab.ui.control.Image
        Image_2                         matlab.ui.control.Image
        ExportphaseButtonGroup          matlab.ui.container.ButtonGroup
        wrappedButton                   matlab.ui.control.RadioButton
        unwrappedButton                 matlab.ui.control.RadioButton
        TimeEditField                   matlab.ui.control.EditField
        TimeEditFieldLabel              matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        DateDatePicker                  matlab.ui.control.DatePicker
        DateDatePickerLabel             matlab.ui.control.Label
        DaysEditField                   matlab.ui.control.NumericEditField
        DaysEditFieldLabel              matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        FilenameEditField               matlab.ui.control.EditField
        FilenameEditFieldLabel          matlab.ui.control.Label
        ExportnamefortheoutputtablesxlsxandcsvLabel  matlab.ui.control.Label
        InitialdateoftheanalyzedtimeseriesLabel  matlab.ui.control.Label
        StaMPS1Tab                      matlab.ui.container.Tab
        Image2_3                        matlab.ui.control.Image
        Image_3                         matlab.ui.control.Image
        wavelengthinmetersLabel         matlab.ui.control.Label
        lambdaEditField                 matlab.ui.control.NumericEditField
        lambdaEditFieldLabel            matlab.ui.control.Label
        degreeswithrespecttonorthLabel  matlab.ui.control.Label
        headingEditField                matlab.ui.control.NumericEditField
        headingEditFieldLabel           matlab.ui.control.Label
        NumberofcoresEditField          matlab.ui.control.NumericEditField
        NumberofcoresEditFieldLabel     matlab.ui.control.Label
        StaMPSlaststepDropDown          matlab.ui.control.DropDown
        StaMPSlaststepDropDownLabel     matlab.ui.control.Label
        StaMPSfirststepDropDown         matlab.ui.control.DropDown
        StaMPSfirststepDropDownLabel    matlab.ui.control.Label
        TRAINatmosphericcorrectionCheckBox  matlab.ui.control.CheckBox
        Step1LoaddataLabel              matlab.ui.control.Label
        StaMPS2Tab                      matlab.ui.container.Tab
        Image2_4                        matlab.ui.control.Image
        Image_4                         matlab.ui.control.Image
        CLAPfilterspatialwavelengthcutoffmLabel  matlab.ui.control.Label
        CLAPfilterbetatermLabel         matlab.ui.control.Label
        CLAPfilteralphatermLabel        matlab.ui.control.Label
        CLAPfilterwindowsizeLabel       matlab.ui.control.Label
        quickestimationofgammacoherenceLabel  matlab.ui.control.Label
        thresholdforchangeinmeanvalueofcoherenceLabel  matlab.ui.control.Label
        maximumnumberofiterationsLabel  matlab.ui.control.Label
        weightingschemeLabel            matlab.ui.control.Label
        pixelsizeofresamplinggridLabel  matlab.ui.control.Label
        maxuncorrelatedDEMerrormLabel   matlab.ui.control.Label
        clap_low_pass_wavelengthEditField  matlab.ui.control.NumericEditField
        clap_low_pass_wavelengthEditFieldLabel  matlab.ui.control.Label
        clap_betaEditField              matlab.ui.control.NumericEditField
        clap_betaEditFieldLabel         matlab.ui.control.Label
        clap_alphaEditField             matlab.ui.control.NumericEditField
        clap_alphaEditFieldLabel        matlab.ui.control.Label
        clap_winEditField               matlab.ui.control.NumericEditField
        clap_winEditFieldLabel          matlab.ui.control.Label
        small_baseline_flagEditField    matlab.ui.control.EditField
        small_baseline_flagEditFieldLabel  matlab.ui.control.Label
        quick_est_gamma_flagEditField   matlab.ui.control.EditField
        quick_est_gamma_flagEditFieldLabel  matlab.ui.control.Label
        gamma_stdev_rejectEditField     matlab.ui.control.NumericEditField
        gamma_stdev_rejectEditFieldLabel  matlab.ui.control.Label
        gamma_change_convergenceEditField  matlab.ui.control.NumericEditField
        gamma_change_convergenceEditFieldLabel  matlab.ui.control.Label
        filter_weightingEditField       matlab.ui.control.EditField
        filter_weightingEditFieldLabel  matlab.ui.control.Label
        gamma_max_iterationsEditField   matlab.ui.control.NumericEditField
        gamma_max_iterationsEditFieldLabel  matlab.ui.control.Label
        filter_grid_sizeEditField       matlab.ui.control.NumericEditField
        filter_grid_sizeEditFieldLabel  matlab.ui.control.Label
        max_topo_errEditField           matlab.ui.control.NumericEditField
        max_topo_errEditFieldLabel      matlab.ui.control.Label
        Step2EstimatePhaseNoiseLabel    matlab.ui.control.Label
        StaMPS3Tab                      matlab.ui.container.Tab
        Image2_5                        matlab.ui.control.Image
        Image_5                         matlab.ui.control.Image
        thresholdstandarddeviationforresamplingLabel  matlab.ui.control.Label
        coarserpostingtoresampletom0noresamplingisappliedLabel  matlab.ui.control.Label
        thresholdforthemaximumnoiseallowedforapixelLabel  matlab.ui.control.Label
        flagforzeroelevationweedingLabel  matlab.ui.control.Label
        flagforproximityweedingLabel    matlab.ui.control.Label
        thresholdstandarddeviationLabel  matlab.ui.control.Label
        maximumacceptablepercentagedenistyLabel  matlab.ui.control.Label
        selectionmethodforpixelswithrandomphaseLabel  matlab.ui.control.Label
        weed_max_noiseEditField         matlab.ui.control.NumericEditField
        weed_max_noiseEditFieldLabel    matlab.ui.control.Label
        merge_standard_devEditField     matlab.ui.control.NumericEditField
        merge_standard_devEditFieldLabel  matlab.ui.control.Label
        merge_resample_sizeEditField    matlab.ui.control.NumericEditField
        merge_resample_sizeEditFieldLabel  matlab.ui.control.Label
        Step5PhasecorrectionLabel       matlab.ui.control.Label
        weed_zero_elevationEditField    matlab.ui.control.EditField
        weed_zero_elevationEditFieldLabel  matlab.ui.control.Label
        weed_neighboursEditField        matlab.ui.control.EditField
        weed_neighboursEditFieldLabel   matlab.ui.control.Label
        weed_standard_devEditField      matlab.ui.control.NumericEditField
        weed_standard_devEditFieldLabel  matlab.ui.control.Label
        Step4PSweedingLabel             matlab.ui.control.Label
        weed_time_winDescriptionLabel   matlab.ui.control.Label
        weed_time_winEditField          matlab.ui.control.NumericEditField
        weed_time_winEditFieldLabel     matlab.ui.control.Label
        select_methodDropDown           matlab.ui.control.DropDown
        select_methodDropDownLabel      matlab.ui.control.Label
        density_randEditField           matlab.ui.control.NumericEditField
        density_randEditFieldLabel      matlab.ui.control.Label
        percent_randEditField           matlab.ui.control.NumericEditField
        percent_randEditFieldLabel      matlab.ui.control.Label
        Step3PSselectionLabel           matlab.ui.control.Label
        StaMPS4Tab                      matlab.ui.container.Tab
        Image2_6                        matlab.ui.control.Image
        Image_6                         matlab.ui.control.Image
        removeselectedinterferogramsmeansnoalternativelyie13Label  matlab.ui.control.Label
        methodfortroposphericcorrectionTRAINLabel  matlab.ui.control.Label
        subtracttroposphericeffectTRAINLabel  matlab.ui.control.Label
        flagforlookangleLabel           matlab.ui.control.Label
        usethepatchphaseofstep3asprefilteredphaseLabel  matlab.ui.control.Label
        prefilterphasebeforeunwrappingtoreducenoiseLabel  matlab.ui.control.Label
        valueofalphaforGoldsteinfilterLabel  matlab.ui.control.Label
        unwrappingmethodLabel           matlab.ui.control.Label
        windowsizeforGoldsteinfilterLabel  matlab.ui.control.Label
        resamplinggridspacingLabel      matlab.ui.control.Label
        drop_ifg_indexEditField         matlab.ui.control.EditField
        drop_ifg_indexEditFieldLabel    matlab.ui.control.Label
        select_reest_gamma_flagEditField  matlab.ui.control.EditField
        select_reest_gamma_flagEditFieldLabel  matlab.ui.control.Label
        tropo_methodEditField           matlab.ui.control.EditField
        tropo_methodEditFieldLabel      matlab.ui.control.Label
        subtr_tropoEditField            matlab.ui.control.EditField
        subtr_tropoEditFieldLabel       matlab.ui.control.Label
        unwrap_hold_good_valuesEditField  matlab.ui.control.EditField
        unwrap_hold_good_valuesEditFieldLabel  matlab.ui.control.Label
        unwrap_la_error_flagEditField   matlab.ui.control.EditField
        unwrap_la_error_flagEditFieldLabel  matlab.ui.control.Label
        unwrap_patch_phaseEditField     matlab.ui.control.EditField
        unwrap_patch_phaseEditFieldLabel  matlab.ui.control.Label
        unwrap_prefilter_flagEditField  matlab.ui.control.EditField
        unwrap_prefilter_flagEditFieldLabel  matlab.ui.control.Label
        unwrap_spatial_cost_func_flagEditField  matlab.ui.control.EditField
        unwrap_spatial_cost_func_flagEditFieldLabel  matlab.ui.control.Label
        unwrap_alphaEditField           matlab.ui.control.NumericEditField
        unwrap_alphaEditFieldLabel      matlab.ui.control.Label
        unwrap_gold_alphaEditField      matlab.ui.control.NumericEditField
        unwrap_gold_alphaEditFieldLabel  matlab.ui.control.Label
        unwrap_methodEditField          matlab.ui.control.EditField
        unwrap_methodEditFieldLabel     matlab.ui.control.Label
        unwrap_gold_n_winEditField      matlab.ui.control.NumericEditField
        unwrap_gold_n_winEditFieldLabel  matlab.ui.control.Label
        unwrap_grid_sizeEditField       matlab.ui.control.NumericEditField
        unwrap_grid_sizeEditFieldLabel  matlab.ui.control.Label
        Step6PhaseunwrappingLabel       matlab.ui.control.Label
        unwrap_time_winDescriptionLabel  matlab.ui.control.Label
        unwrap_time_winEditField        matlab.ui.control.NumericEditField
        unwrap_time_winEditFieldLabel   matlab.ui.control.Label
        StaMPS5Tab                      matlab.ui.container.Tab
        Image2_7                        matlab.ui.control.Image
        Image_7                         matlab.ui.control.Image
        spatialfilterLabel              matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        sclamethodLabel                 matlab.ui.control.Label
        flagforphaserampestimationLabel  matlab.ui.control.Label
        scn_wavelengthEditField         matlab.ui.control.NumericEditField
        scn_wavelengthEditFieldLabel    matlab.ui.control.Label
        scn_kriging_flagEditField       matlab.ui.control.EditField
        scn_kriging_flagEditFieldLabel  matlab.ui.control.Label
        Step8AtmosphericfilteringLabel  matlab.ui.control.Label
        scn_time_winDescriptionLabel    matlab.ui.control.Label
        scn_time_winEditField           matlab.ui.control.NumericEditField
        scn_time_winEditFieldLabel      matlab.ui.control.Label
        scla_drop_indexEditField        matlab.ui.control.EditField
        scla_drop_indexEditFieldLabel   matlab.ui.control.Label
        scla_methodEditField            matlab.ui.control.EditField
        scla_methodEditFieldLabel       matlab.ui.control.Label
        scla_derampEditField            matlab.ui.control.EditField
        scla_derampEditFieldLabel       matlab.ui.control.Label
        Step7EstimatespatiallycorrelatedlookangleerrorLabel  matlab.ui.control.Label
        StaMPSAOITab                    matlab.ui.container.Tab
        Image2_8                        matlab.ui.control.Image
        Image_8                         matlab.ui.control.Image
        ref_radiusEditField_w           matlab.ui.control.NumericEditField
        ref_radiusEditField_2Label      matlab.ui.control.Label
        ref_centre_latEditField_w       matlab.ui.control.NumericEditField
        ref_centre_latEditField_2Label  matlab.ui.control.Label
        ref_centre_lonEditField_w       matlab.ui.control.NumericEditField
        ref_centre_lonEditField_2Label  matlab.ui.control.Label
        radiusforPSselectionmLabel_w    matlab.ui.control.Label
        latitudedegcoordinatesoftheunwrappingspatialreferenceLabel_w  matlab.ui.control.Label
        longitudedegcoordinatesoftheunwrappingspatialreferenceLabel_w  matlab.ui.control.Label
        PlotsandAOIsettingsWRAPPEDPHASELabel  matlab.ui.control.Label
        dimensionofPSpixelsintheplotLabel  matlab.ui.control.Label
        referencevelocityofunwrappingreferenceLabel  matlab.ui.control.Label
        radiusforPSselectionmLabel      matlab.ui.control.Label
        latitudedegcoordinatesoftheunwrappingspatialreferenceLabel  matlab.ui.control.Label
        longitudedegcoordinatesoftheunwrappingspatialreferenceLabel  matlab.ui.control.Label
        plot_sEditField                 matlab.ui.control.NumericEditField
        plot_sEditFieldLabel            matlab.ui.control.Label
        ref_velocityEditField           matlab.ui.control.NumericEditField
        ref_velocityEditFieldLabel      matlab.ui.control.Label
        ref_radiusEditField             matlab.ui.control.NumericEditField
        ref_radiusEditFieldLabel        matlab.ui.control.Label
        ref_centre_latEditField         matlab.ui.control.NumericEditField
        ref_centre_latEditFieldLabel    matlab.ui.control.Label
        ref_centre_lonEditField         matlab.ui.control.NumericEditField
        ref_centre_lonEditFieldLabel    matlab.ui.control.Label
        PlotsandAOIsettingsUNWRAPPEDPHASELabel  matlab.ui.control.Label
        SaveLoadTab                     matlab.ui.container.Tab
        Image2_9                        matlab.ui.control.Image
        Image_9                         matlab.ui.control.Image
        StatusLamp_3                    matlab.ui.control.Lamp
        StatusLamp_3Label               matlab.ui.control.Label
        LoadButton                      matlab.ui.control.Button
        SavetheconfiguredparametersfortheInSARpreprocessingLabel_3  matlab.ui.control.Label
        StatusLamp                      matlab.ui.control.Lamp
        StatusLampLabel                 matlab.ui.control.Label
        SavetheconfiguredparametersfortheInSARpreprocessingLabel  matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        RunTab                          matlab.ui.container.Tab
        Image2_10                       matlab.ui.control.Image
        Image_10                        matlab.ui.control.Image
        MessagesTextArea                matlab.ui.control.TextArea
        MessagesTextArea_3Label         matlab.ui.control.Label
        PreprocessingstatusLamp         matlab.ui.control.Lamp
        PreprocessingstatusLampLabel    matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        StartButton                     matlab.ui.control.Button
        Label_6                         matlab.ui.control.Label
    end

    
    properties (Access = private)
        
        % Variables initialization with a default value
        stamps_preparation = 0;
        amplitude_threshold = 0.40;
        master_date = '20200305';
        export_name = 'filename';
        time_span = 0;		
        weed_time_win = 730;
        unwrap_time_win = 730;
        scn_time_win = 365;
        year_0 = 2019;		
        month_0 = 7;			
        day_0 = 9;			
        utc_time = '10:30';
        train_flag = 0;
        stamps_first_step = 1;
        stamps_last_step = 7;
        n_cores = 4;
        heading = 346.18;
        lambda = 0.055465763;
        max_topo_err = 16;				
        filter_grid_size = 40;			
        filter_weighting = 'P-square';		
        gamma_max_iterations = 7;		
        gamma_change_convergence = 0.0050;	
        gamma_stdev_reject = 0;
        quick_est_gamma_flag = 'y';		
        small_baseline_flag = 'n';
        clap_win = 16;			
        clap_alpha = 1;				
        clap_beta = 0.3000;				
        clap_low_pass_wavelength = 800;
        select_method = 'PERCENT';
        percent_rand = 1;
        density_rand = 25;
        weed_standard_dev = 1;	
        weed_neighbours = 'y';	
        weed_zero_elevation = 'n';	
        weed_max_noise = Inf;	
        merge_resample_size = 0;
        merge_standard_dev = Inf;
        unwrap_grid_size = 20;		
        unwrap_gold_n_win = 16;				
        unwrap_method = '3D';			
        unwrap_gold_alpha = 0.8;			
        unwrap_alpha = 8;
        unwrap_spatial_cost_func_flag = 'n';
        unwrap_prefilter_flag = 'y';
        unwrap_patch_phase = 'n';		
        unwrap_la_error_flag = 'y';			
        unwrap_hold_good_values = 'y';
        subtr_tropo = 'y';	
        tropo_method = 'a_gacos';		
        select_reest_gamma_flag = 'y';
        drop_ifg_index = [];
        scla_deramp = 'y';
        scla_method = 'L2';	
        scla_drop_index = [];
        scn_wavelength = 50		
        scn_kriging_flag = 'n';
        ref_centre_lon = 0.0;
        ref_centre_lat = 0.0;
        ref_radius = 0;		
        ref_velocity = 0;					
        plot_s = 15;
        ref_centre_lon_w = 0.0;
        ref_centre_lat_w = 0.0;
        ref_radius_w = 0;
        ph_output = 'unwrapped';

    end
    
    methods (Access = private)
        
        function updateOutput(app, message)   % Function to mirror the command window

            % Append the message to the existing text in the TextArea
            currentText = app.MessagesTextArea.Value;
            newText = [currentText; message];
            app.MessagesTextArea.Value = newText;
            drawnow;
            
        end


        function AutoDetectParameters(app) % Function to auto-fill UI from SNAP output
            try
                updateOutput(app, 'Starting automatic parameter detection...');
                
                % 1. Auto-detect Master Date from INSAR_ folder
                currentFolder = fileparts(mfilename('fullpath'));
                preprocFolder = fullfile(currentFolder, '..', 'PHASE_Preprocessing');
                insarDir = dir(fullfile(preprocFolder, 'INSAR_*'));
                
                if ~isempty(insarDir) && insarDir(1).isdir
                    folderName = insarDir(1).name;
                    extractedDate = extractAfter(folderName, 'INSAR_');
                    app.master_date = extractedDate;
                    
                    try
                        app.MasterdateDatePicker.Value = datetime(extractedDate, 'InputFormat', 'yyyyMMdd');
                        % SUCCESS: Lock the UI field
                        app.MasterdateDatePicker.Enable = 'off';
                    catch
                        updateOutput(app, 'Warning: Found INSAR folder, but date format is invalid. Manual input required.');
                    end
                    
                    % 2. Locate the diff0 folder
                    diff0Path = fullfile(preprocFolder, folderName, 'diff0');
                    parFiles = dir(fullfile(diff0Path, '*.diff.par'));
                    
                    if ~isempty(parFiles)
                        allDates = [];
                        for i = 1:length(parFiles)
                            tokens = regexp(parFiles(i).name, '(\d{8})_(\d{8})', 'tokens');
                            if ~isempty(tokens)
                                allDates = [allDates, datetime(tokens{1}{1}, 'InputFormat', 'yyyyMMdd')];
                                allDates = [allDates, datetime(tokens{1}{2}, 'InputFormat', 'yyyyMMdd')];
                            end
                        end
                        
                        % Calculate Initial Date and Time Span
                        if ~isempty(allDates)
                            minDate = min(allDates);
                            maxDate = max(allDates);
                            
                            app.year_0 = year(minDate);
                            app.month_0 = month(minDate);
                            app.day_0 = day(minDate);
                            app.time_span = days(maxDate - minDate);
                            
                            app.DateDatePicker.Value = minDate;
                            app.DaysEditField.Value = app.time_span;
                            
                            % SUCCESS: Lock the UI fields
                            app.DateDatePicker.Enable = 'off';
                            app.DaysEditField.Enable = 'off';
                        end

                        % Extract Metadata from the first .par file
                        parText = fileread(fullfile(diff0Path, parFiles(1).name));
                        
                        % Extract Heading
                        headMatch = regexp(parText, 'heading:\s+([\d\.]+)', 'tokens');
                        if ~isempty(headMatch)
                            app.headingEditField.Value = str2double(headMatch{1}{1});
                            % SUCCESS: Lock the UI field
                            app.headingEditField.Enable = 'off';
                        end
                        
                        % Extract Radar Frequency and Calculate Lambda
                        freqMatch = regexp(parText, 'radar_frequency:\s+([\d\.E]+)', 'tokens');
                        if ~isempty(freqMatch)
                            freq = str2double(freqMatch{1}{1});
                            c = 299792458;
                            app.lambdaEditField.Value = c / freq;
                            % SUCCESS: Lock the UI field
                            app.lambdaEditField.Enable = 'off';
                        end
                        
                        % Extract Center Time and format to HH:MM (rounded to 10 mins)
                        timeMatch = regexp(parText, 'center_time:\s+([\d\.]+)', 'tokens');
                        if ~isempty(timeMatch)
                            secondsOfDay = str2double(timeMatch{1}{1});
                            h = floor(secondsOfDay / 3600);
                            m = floor(rem(secondsOfDay, 3600) / 60);
                            
                            m_rounded = round(m / 10) * 10; 
                            if m_rounded == 60
                                h = h + 1;
                                m_rounded = 0;
                            end
                            if h == 24
                                h = 0;
                            end
                            app.TimeEditField.Value = sprintf('%02d:%02d', h, m_rounded);
                            % SUCCESS: Lock the UI field
                            app.TimeEditField.Enable = 'off';
                        end
                        
                        updateOutput(app, 'Successfully extracted parameters from .diff.par files.');
                    else
                        updateOutput(app, 'Warning: diff0 folder or .par files not found. Please input parameters manually.');
                    end
                else
                    updateOutput(app, 'Warning: INSAR_ folder not found. Cannot auto-detect parameters.');
                end
            catch exception
                updateOutput(app, ['Error during auto-detection: ' exception.message '. Please check manual inputs.']);
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Set the working directory to the folder where the .mlapp file is located
            currentFolder = fileparts(mfilename('fullpath'));
            cd(currentFolder);

            % Wire StaMPS matlab + matlab_compat onto the path so the
            % TS Points picker (ts_export_picker / ts_export_batch /
            % llh2local) resolves regardless of the user's startup.m.
            %
            % NOTE: PathEditField_2 = StaMPS installation folder (label
            % StaMPSinstallationfolderLabel), PathEditField_3 = Project
            % folder (label ProjectfolderLabel). These names are
            % inherited from the existing PHASE_StaMPS layout. If the
            % .mlapp tabs are reordered, also update the references in
            % OpenTSPickerButtonPushed below.
            stamps_root = app.PathEditField_2.Value;
            if ~isempty(stamps_root) && isfolder(stamps_root)
                addpath(fullfile(stamps_root, 'matlab'));
                addpath(fullfile(stamps_root, 'matlab_compat'));

                % Add StaMPS native binaries to the MATLAB process PATH so
                % sp_system('triangle ...'), sp_system('snaphu ...') etc.
                % find the .exe files. mt_prep_snap.bat sets these for its
                % own process only; MATLAB's system() inherits the parent
                % shell's PATH which by default does not include them.
                bin_paths = {fullfile(stamps_root, 'bin'), ...
                             fullfile(stamps_root, 'external', 'triangle', 'bin'), ...
                             fullfile(stamps_root, 'external', 'snaphu', 'bin')};
                current_path = getenv('PATH');
                path_segments = strsplit(current_path, pathsep);
                for k = 1:length(bin_paths)
                    if isfolder(bin_paths{k}) && ~any(strcmpi(path_segments, bin_paths{k}))
                        current_path = [bin_paths{k}, pathsep, current_path];
                    end
                end
                setenv('PATH', current_path);
            end
            
            % Build absolute path for the logo
            logoPath = fullfile(currentFolder, '..', 'PHASE_logo.png');
            app.Image.ImageSource = logoPath;
            app.Image_2.ImageSource = logoPath;
            app.Image_3.ImageSource = logoPath;
            app.Image_4.ImageSource = logoPath;
            app.Image_5.ImageSource = logoPath;
            app.Image_6.ImageSource = logoPath;
            app.Image_7.ImageSource = logoPath;
            app.Image_8.ImageSource = logoPath;
            app.Image_9.ImageSource = logoPath;
            app.Image_10.ImageSource = logoPath;

            % module
            imagePath = fullfile(currentFolder, '..', 'PHASE_mod1b.png');
            app.Image2.ImageSource = imagePath;
            app.Image2_2.ImageSource = imagePath;
            app.Image2_3.ImageSource = imagePath;
            app.Image2_4.ImageSource = imagePath;
            app.Image2_5.ImageSource = imagePath;
            app.Image2_6.ImageSource = imagePath;
            app.Image2_7.ImageSource = imagePath;
            app.Image2_8.ImageSource = imagePath;
            app.Image2_9.ImageSource = imagePath;
            app.Image2_10.ImageSource = imagePath;

            app.percent_randEditField.Visible = 'on';
            app.percent_randEditFieldLabel.Visible = 'on';
            app.density_randEditField.Visible = 'off';
            app.density_randEditFieldLabel.Visible = 'off';

            % Trigger automatic parameter extraction
            AutoDetectParameters(app);

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
            % Specify the filename for saving
            filename = './input_StaMPS.mat';  % specify the filename for saving

            stamps_preparation = app.stamps_preparation;
            installation_folder = app.PathEditField_2.Value;
            project_path = app.PathEditField_3.Value;
            amplitude_threshold = app.ValueEditField.Value;
            master_date = app.master_date;
            export_name = app.FilenameEditField.Value;
            time_span = app.DaysEditField.Value;		
            weed_time_win = app.weed_time_winEditField.Value;
            unwrap_time_win = app.unwrap_time_winEditField.Value;
            scn_time_win = app.scn_time_winEditField.Value;
            year_0 = app.year_0;	
            month_0 = app.month_0;
            day_0 = app.day_0;
            utc_time = app.TimeEditField.Value;
            train_flag = app.train_flag;
            stamps_first_step = app.StaMPSfirststepDropDown.Value;
            stamps_last_step = app.StaMPSlaststepDropDown.Value;
            n_cores = app.NumberofcoresEditField.Value;
            heading = app.headingEditField.Value;
            lambda = app.lambdaEditField.Value;
            max_topo_err = app.max_topo_errEditField.Value;				
            filter_grid_size = app.filter_grid_sizeEditField.Value;			
            filter_weighting = app.filter_weightingEditField.Value;		
            gamma_max_iterations = app.gamma_max_iterationsEditField.Value;		
            gamma_change_convergence = app.gamma_change_convergenceEditField.Value;	
            gamma_stdev_reject = app.gamma_stdev_rejectEditField.Value;
            quick_est_gamma_flag = app.quick_est_gamma_flagEditField.Value;		
            small_baseline_flag = app.small_baseline_flagEditField.Value;
            clap_win = app.clap_winEditField.Value;			
            clap_alpha = app.clap_alphaEditField.Value;				
            clap_beta = app.clap_betaEditField.Value;				
            clap_low_pass_wavelength = app.clap_low_pass_wavelengthEditField.Value;
            select_method = app.select_methodDropDown.Value;
            percent_rand = app.percent_randEditField.Value;
            density_rand = app.density_randEditField.Value;
            weed_standard_dev = app.weed_standard_devEditField.Value;	
            weed_neighbours = app.weed_neighboursEditField.Value;	
            weed_zero_elevation = app.weed_zero_elevationEditField.Value;	
            weed_max_noise = app.weed_max_noiseEditField.Value;	
            merge_resample_size = app.merge_resample_sizeEditField.Value;
            merge_standard_dev = app.merge_standard_devEditField.Value;
            unwrap_grid_size = app.unwrap_grid_sizeEditField.Value;		
            unwrap_gold_n_win = app.unwrap_gold_n_winEditField.Value;				
            unwrap_method = app.unwrap_methodEditField.Value;			
            unwrap_gold_alpha = app.unwrap_gold_alphaEditField.Value;			
            unwrap_alpha = app.unwrap_alphaEditField.Value;
            unwrap_spatial_cost_func_flag = app.unwrap_spatial_cost_func_flagEditField.Value;
            unwrap_prefilter_flag = app.unwrap_prefilter_flagEditField.Value;
            unwrap_patch_phase = app.unwrap_patch_phaseEditField.Value;		
            unwrap_la_error_flag = app.unwrap_la_error_flagEditField.Value;			
            unwrap_hold_good_values = app.unwrap_hold_good_valuesEditField.Value;
            subtr_tropo = app.subtr_tropoEditField.Value;	
            tropo_method = app.tropo_methodEditField.Value;		
            select_reest_gamma_flag = app.select_reest_gamma_flagEditField.Value;
            drop_ifg_index = app.drop_ifg_indexEditField.Value;
            scla_deramp = app.scla_derampEditField.Value;
            scla_method = app.scla_methodEditField.Value;	
            scla_drop_index = app.scla_drop_indexEditField.Value;
            scn_wavelength = app.scn_wavelengthEditField.Value;
            scn_kriging_flag = app.scn_kriging_flagEditField.Value;
            ref_centre_lonlat = [app.ref_centre_lonEditField.Value app.ref_centre_latEditField.Value];
            ref_radius = app.ref_radiusEditField.Value;
            ref_velocity = app.ref_velocityEditField.Value;			
            plot_s = app.plot_sEditField.Value;
            ref_centre_lonlat_w = [app.ref_centre_lonEditField_w.Value app.ref_centre_latEditField_w.Value];
            ref_radius_w = app.ref_radiusEditField_w.Value;
            ph_output = app.ExportphaseButtonGroup.SelectedObject.Text;

            try

                % Delete the existing .mat file, if it exists
                if exist(filename, 'file') == 2
                    builtin('delete', filename);
                end

                save(filename, 'stamps_preparation', 'installation_folder', 'project_path', 'amplitude_threshold', 'master_date', 'export_name', 'time_span', 'weed_time_win', 'unwrap_time_win', 'scn_time_win', ...
                    'year_0', 'month_0', 'day_0', 'utc_time', 'train_flag', 'stamps_first_step', 'stamps_last_step', ...
                    'n_cores', 'heading', 'lambda', 'max_topo_err', 'filter_grid_size', 'filter_weighting', ...
                    'gamma_max_iterations', 'gamma_change_convergence', 'gamma_stdev_reject', 'quick_est_gamma_flag', ...
                    'small_baseline_flag', 'clap_win', 'clap_alpha', 'clap_beta', 'clap_low_pass_wavelength', ...
                    'select_method', 'percent_rand', 'weed_standard_dev', 'weed_neighbours', 'weed_zero_elevation', ...
                    'weed_max_noise', 'merge_resample_size', 'merge_standard_dev', 'unwrap_grid_size', ...
                    'unwrap_gold_n_win', 'unwrap_method', 'unwrap_gold_alpha', 'unwrap_alpha', 'unwrap_spatial_cost_func_flag', ...
                    'unwrap_prefilter_flag', 'unwrap_patch_phase', 'unwrap_la_error_flag', 'unwrap_hold_good_values', ...
                    'subtr_tropo', 'tropo_method', 'select_reest_gamma_flag', 'drop_ifg_index', 'scla_deramp', ...
                    'scla_method', 'scla_drop_index', 'scn_wavelength', 'scn_kriging_flag', 'ref_centre_lonlat', ...
                    'ref_radius', 'ref_velocity', 'plot_s', 'ref_centre_lonlat_w', 'ref_radius_w', 'ph_output', '-mat');

                % If saving is successful, set the lamp to green
                app.StatusLamp.Color = [0, 1, 0]; % Green color

            catch exception

                % If saving fails, set the lamp to red
                app.StatusLamp.Color = [1, 0, 0]; % Red color
                % Display the error message
                updateOutput(app, ['Error saving file: ' exception.message]);

            end

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
            app.MessagesTextArea.Value = '';

            % Display a message in the UIAxes
            updateOutput(app, 'Button pressed! Running the script...');

            % Change lamp color to yellow (running)
            app.PreprocessingstatusLamp.Color = [1, 1, 0]; % Yellow
        
            try

                clc;
                
                %% ------------------ IMPORT OF THE REQUIRED VARIABLES --------------------
                
                load('input_StaMPS.mat', 'stamps_preparation', 'installation_folder', 'project_path', 'amplitude_threshold', 'master_date', 'export_name', 'time_span', 'weed_time_win', 'unwrap_time_win', 'scn_time_win', ...
                    'year_0', 'month_0', 'day_0', 'utc_time', 'train_flag', 'stamps_first_step', 'stamps_last_step', ...
                    'n_cores', 'heading', 'lambda', 'max_topo_err', 'filter_grid_size', 'filter_weighting', ...
                    'gamma_max_iterations', 'gamma_change_convergence', 'gamma_stdev_reject', 'quick_est_gamma_flag', ...
                    'small_baseline_flag', 'clap_win', 'clap_alpha', 'clap_beta', 'clap_low_pass_wavelength', ...
                    'select_method', 'percent_rand', 'weed_standard_dev', 'weed_neighbours', 'weed_zero_elevation', ...
                    'weed_max_noise', 'merge_resample_size', 'merge_standard_dev', 'unwrap_grid_size', ...
                    'unwrap_gold_n_win', 'unwrap_method', 'unwrap_gold_alpha', 'unwrap_alpha', 'unwrap_spatial_cost_func_flag', ...
                    'unwrap_prefilter_flag', 'unwrap_patch_phase', 'unwrap_la_error_flag', 'unwrap_hold_good_values', ...
                    'subtr_tropo', 'tropo_method', 'select_reest_gamma_flag', 'drop_ifg_index', 'scla_deramp', ...
                    'scla_method', 'scla_drop_index', 'scn_wavelength', 'scn_kriging_flag', 'ref_centre_lonlat', ...
                    'ref_radius', 'ref_velocity', 'plot_s', 'ref_centre_lonlat_w', 'ref_radius_w', 'ph_output');

                % begin independent StaMPS temporal windows
                legacy_time_window_fields = {};
                if exist('weed_time_win', 'var') ~= 1
                    weed_time_win = time_span;
                    legacy_time_window_fields{end + 1} = 'weed_time_win';
                end
                if exist('unwrap_time_win', 'var') ~= 1
                    unwrap_time_win = time_span;
                    legacy_time_window_fields{end + 1} = 'unwrap_time_win';
                end
                if exist('scn_time_win', 'var') ~= 1
                    scn_time_win = time_span;
                    legacy_time_window_fields{end + 1} = 'scn_time_win';
                end
                if ~isempty(legacy_time_window_fields)
                    updateOutput(app, ['Legacy input_StaMPS.mat: missing ' ...
                        strjoin(legacy_time_window_fields, ', ') ...
                        '; using time_span=' num2str(time_span) ...
                        ' days for backward compatibility. Press Save to migrate the MAT file.']);
                end
                % end independent StaMPS temporal windows

                % begin saved/visible tropospheric-configuration guard
                % The app deliberately has separate Load and Save buttons. If
                % a user edits these controls without Save, Start would load an
                % older value, launch GACOS/seek tca2, or apply a wrong temporal window.
                % Never overwrite the MAT file here: other untouched controls
                % may still contain the app's compiled defaults until Load.
                loaded_subtr_tropo = char(string(subtr_tropo));
                visible_subtr_tropo = char(string(app.subtr_tropoEditField.Value));
                loaded_tropo_method = char(string(tropo_method));
                visible_tropo_method = char(string(app.tropo_methodEditField.Value));
                visible_train_flag = double(~app.TRAINatmosphericcorrectionCheckBox.Value);
                visible_weed_time_win = app.weed_time_winEditField.Value;
                visible_unwrap_time_win = app.unwrap_time_winEditField.Value;
                visible_scn_time_win = app.scn_time_winEditField.Value;
                % begin selected StaMPS step guard
                loaded_stamps_first_step = str2double(char(string(stamps_first_step)));
                loaded_stamps_last_step = str2double(char(string(stamps_last_step)));
                visible_stamps_first_step = str2double(char(string(app.StaMPSfirststepDropDown.Value)));
                visible_stamps_last_step = str2double(char(string(app.StaMPSlaststepDropDown.Value)));
                % end selected StaMPS step guard
                tropo_config_mismatch = ...
                    ~strcmpi(strtrim(loaded_subtr_tropo), strtrim(visible_subtr_tropo)) || ...
                    ~strcmpi(strtrim(loaded_tropo_method), strtrim(visible_tropo_method)) || ...
                    train_flag ~= visible_train_flag;
                time_window_config_mismatch = ...
                    ~isequaln(weed_time_win, visible_weed_time_win) || ...
                    ~isequaln(unwrap_time_win, visible_unwrap_time_win) || ...
                    ~isequaln(scn_time_win, visible_scn_time_win);
                step_range_config_mismatch = ...
                    ~isequaln(loaded_stamps_first_step, visible_stamps_first_step) || ...
                    ~isequaln(loaded_stamps_last_step, visible_stamps_last_step);
                if tropo_config_mismatch || time_window_config_mismatch || step_range_config_mismatch
                    error('PHASE_StaMPS:unsavedConfiguration', ...
                        ['The visible TRAIN/tropospheric/temporal-window/step controls do not match input_StaMPS.mat. ', ...
                         'Processing was not started and the MAT file was not changed. ', ...
                         'Press Load, make the intended changes, press Save, then press Start. ', ...
                         'Saved: TRAIN=%d, subtr_tropo=%s, tropo_method=%s, time windows=%g/%g/%g days, steps=%g->%g. ', ...
                         'Visible: TRAIN=%d, subtr_tropo=%s, tropo_method=%s, time windows=%g/%g/%g days, steps=%g->%g.'], ...
                         train_flag == 0, loaded_subtr_tropo, loaded_tropo_method, ...
                         weed_time_win, unwrap_time_win, scn_time_win, ...
                         loaded_stamps_first_step, loaded_stamps_last_step, ...
                         visible_train_flag == 0, visible_subtr_tropo, visible_tropo_method, ...
                         visible_weed_time_win, visible_unwrap_time_win, visible_scn_time_win, ...
                         visible_stamps_first_step, visible_stamps_last_step);
                end
                updateOutput(app, sprintf( ...
                    ['Loaded configuration: TRAIN=%d, subtr_tropo=%s, tropo_method=%s, ', ...
                     'weed/unwrap/scn time windows=%g/%g/%g days, StaMPS steps=%g->%g'], ...
                    train_flag == 0, loaded_subtr_tropo, loaded_tropo_method, ...
                    weed_time_win, unwrap_time_win, scn_time_win, ...
                    loaded_stamps_first_step, loaded_stamps_last_step));
                clear loaded_subtr_tropo visible_subtr_tropo loaded_tropo_method
                clear visible_tropo_method visible_train_flag tropo_config_mismatch
                clear visible_weed_time_win visible_unwrap_time_win visible_scn_time_win
                clear time_window_config_mismatch legacy_time_window_fields
                clear loaded_stamps_first_step loaded_stamps_last_step
                clear visible_stamps_first_step visible_stamps_last_step
                clear step_range_config_mismatch
                % end saved/visible tropospheric-configuration guard

                % AutoDetectParameters (startupFcn) reads ground-truth values
                % from INSAR_<date>/diff0/*.diff.par and LOCKS the relevant GUI
                % fields (heading, lambda, master_date, year/month/day,
                % time_span, utc_time). When that succeeds, the GUI is the
                % authoritative source and we MUST override the .mat values:
                % the .mat may carry installer-precompiled placeholders or
                % stale values from a previous project, which would otherwise
                % break mt_prep_snap / ps_load_initial_gamma downstream.
                if isprop(app, 'headingEditField') && strcmp(app.headingEditField.Enable, 'off')
                    heading = app.headingEditField.Value;
                end
                if isprop(app, 'lambdaEditField') && strcmp(app.lambdaEditField.Enable, 'off')
                    lambda = app.lambdaEditField.Value;
                end
                if isprop(app, 'MasterdateDatePicker') && strcmp(app.MasterdateDatePicker.Enable, 'off')
                    master_date = app.master_date;
                end
                if isprop(app, 'DateDatePicker') && strcmp(app.DateDatePicker.Enable, 'off')
                    year_0  = app.year_0;
                    month_0 = app.month_0;
                    day_0   = app.day_0;
                end
                if isprop(app, 'DaysEditField') && strcmp(app.DaysEditField.Enable, 'off')
                    time_span = app.DaysEditField.Value;
                end
                if isprop(app, 'TimeEditField') && strcmp(app.TimeEditField.Enable, 'off')
                    utc_time = app.TimeEditField.Value;
                end

                train_requested = (train_flag == 0) && strcmpi(strtrim(subtr_tropo), 'y');
                % begin TRAIN availability check (Windows port, cross-platform no-op
                % when TRAIN is correctly installed). Multi-function probe
                % avoids false positives from unrelated aps_linear.m on path
                % or incomplete installs. On miss, degrade silently using a
                % LOCAL train_flag override — do not touch app.train_flag nor
                % the checkbox, so the user's original intent is preserved
                % for the next run (they can install TRAIN and press Start
                % again without re-ticking the box).
                train_available = ~isempty(which('aps_linear')) && ...
                                  ~isempty(which('aps_weather_model')) && ...
                                  ~isempty(which('setparm_aps'));
                if train_flag == 0 && ~train_available
                    warning('StaMPS:phase:trainNotAvailable', '%s', ...
                        ['TRAIN not found on MATLAB path (or install is incomplete). ', ...
                         'Install TRAIN (e.g. git clone https://github.com/dbekaert/TRAIN.git) ', ...
                         'then in MATLAB: addpath(genpath(''<TRAIN>/matlab'')); savepath. ', ...
                         'Proceeding without atmospheric correction.']);
                    train_flag = 1;
                end
                % end TRAIN availability check

                % begin TRAIN interactive recovery (Windows-port UX). If the user
                % asked for the correction (train_flag was 0) but TRAIN is not on
                % the path, offer a folder picker to locate it and fix the run on
                % the fly, instead of silently proceeding without correction.
                if train_requested && ~train_available
                    reco_choice = uiconfirm(app.UIFigure, ...
                        ['TRAIN was not found on the MATLAB path, so the tropospheric correction (' tropo_method ') cannot run. ', ...
                         'Locate your TRAIN folder now to fix it, or skip the correction for this run.'], ...
                        'TRAIN not found', ...
                        'Options', {'Locate TRAIN folder...', 'Skip correction'}, ...
                        'DefaultOption', 1, 'CancelOption', 2);
                    if strcmp(reco_choice, 'Locate TRAIN folder...')
                        while true
                            train_dir = uigetdir('', 'Select your TRAIN folder (the one containing the "matlab" subfolder)');
                            if isequal(train_dir, 0)
                                updateOutput(app, 'TRAIN selection cancelled - proceeding without atmospheric correction.');
                                break
                            end
                            if exist(fullfile(train_dir, 'matlab'), 'dir') == 7
                                train_matlab = fullfile(train_dir, 'matlab');
                            else
                                train_matlab = train_dir;
                            end
                            addpath(genpath(train_matlab));
                            train_available = ~isempty(which('aps_linear')) && ...
                                              ~isempty(which('aps_weather_model')) && ...
                                              ~isempty(which('setparm_aps'));
                            if train_available
                                train_flag = 0;
                                updateOutput(app, ['TRAIN located and enabled for this run: ' train_matlab]);
                                persist_choice = uiconfirm(app.UIFigure, ...
                                    'TRAIN added for this session. Make it permanent so you do not have to locate it every time?', ...
                                    'TRAIN located', ...
                                    'Options', {'Make permanent', 'Just this session'}, ...
                                    'DefaultOption', 1, 'CancelOption', 2);
                                if strcmp(persist_choice, 'Make permanent')
                                    if savepath ~= 0
                                        up = userpath;
                                        if isempty(up)
                                            up = fullfile(char(java.lang.System.getProperty('user.home')), 'Documents', 'MATLAB');
                                        end
                                        if exist(up, 'dir') == 0
                                            mkdir(up);
                                        end
                                        fid_su = fopen(fullfile(up, 'startup.m'), 'a');
                                        if fid_su ~= -1
                                            fprintf(fid_su, '\naddpath(genpath(''%s''));\n', strrep(train_matlab, '\', '/'));
                                            fclose(fid_su);
                                            updateOutput(app, ['TRAIN path saved to ' fullfile(up, 'startup.m') ' - it will load at every MATLAB start.']);
                                        end
                                    else
                                        updateOutput(app, 'TRAIN path saved permanently (savepath).');
                                    end
                                end
                                break
                            else
                                retry_choice = uiconfirm(app.UIFigure, ...
                                    ['That folder does not contain TRAIN (aps_linear / aps_weather_model / setparm_aps were not found under it). ', ...
                                     'Choose a different folder, or skip the correction?'], ...
                                    'Not a TRAIN folder', ...
                                    'Options', {'Choose again...', 'Skip correction'}, ...
                                    'DefaultOption', 1, 'CancelOption', 2);
                                if strcmp(retry_choice, 'Skip correction')
                                    updateOutput(app, 'Proceeding without atmospheric correction.');
                                    break
                                end
                            end
                        end
                    else
                        updateOutput(app, 'Proceeding without atmospheric correction (user chose to skip).');
                    end
                end
                % end TRAIN interactive recovery

                % begin effective tropospheric-correction decision
                % TRAIN-dependent preparation, processing and export must all
                % agree. Checking train_flag alone made a subtr_tropo='n' run
                % attempt to export tca2.mat even though no correction existed.
                tropo_correction_enabled = (train_flag == 0) && ...
                    strcmpi(strtrim(subtr_tropo), 'y');
                if train_flag == 0 && ~tropo_correction_enabled
                    updateOutput(app, ['Tropospheric correction disabled by ', ...
                        'subtr_tropo=' char(string(subtr_tropo)) '.']);
                end
                % end effective tropospheric-correction decision

                % Conversion of variables' format
                if isa(master_date, 'datetime')
                    master_date = datestr(master_date, 'yyyymmdd');
                end
                if strcmp(drop_ifg_index, '[]')
                    drop_ifg_index = [];
                else
                    drop_ifg_index = str2double(strsplit(drop_ifg_index));
                end
                if strcmp(scla_drop_index, '[]')
                    scla_drop_index = [];
                else
                    scla_drop_index = str2double(strsplit(scla_drop_index));
                end

                %% -- PREPARATION OF THE SNAP2STAMPS EXPORTED DATA FOR STAMPS PROCESSING --
                
                % PATH DESCRIPTIONS
                cd_fullpath = cd; % folder in which this code is located
                addpath(genpath(installation_folder));
                % Ensure StaMPS native binaries (snaphu, triangle, calamp, ...) are on the
                % process PATH for system() calls, even if the app was launched without the
                % install-folder field set at startup (startupFcn only adds them when set).
                bin_paths = {fullfile(installation_folder,'bin'), fullfile(installation_folder,'external','triangle','bin'), fullfile(installation_folder,'external','snaphu','bin')};
                path_segments = strsplit(getenv('PATH'), pathsep);
                for k = 1:numel(bin_paths)
                    if isfolder(bin_paths{k}) && ~any(strcmpi(path_segments, bin_paths{k}))
                        setenv('PATH', [bin_paths{k}, pathsep, getenv('PATH')]);
                    end
                end
                if isunix
                    stamps_path = which('StaMPS_CONFIG.bash');
                else
                    stamps_path = which('StaMPS_CONFIG.ps1');
                end
                s2s_export_path = strcat(project_path, '/PHASE_Preprocessing/INSAR_', master_date);
                mt_prep_snap = ('mt_prep_snap');
                source = ('source');
                space = (' ');
                amplitude_threshold = (num2str(amplitude_threshold));
                stamps_first_step = str2double(stamps_first_step);
                stamps_last_step = str2double(stamps_last_step);
                
                % DEFINITION OF THE TASKS
                source_stamps = [source space stamps_path];
                source_stamps = strcat(source_stamps, ' -echo');
                source_snap = [mt_prep_snap space master_date space s2s_export_path space amplitude_threshold];
                
                % EXECUTION OF THE TASKS
                if stamps_preparation == 0 % check if the StaMPS preparation has to be done or not
                
                    updateOutput(app, '----------------------- STEP 1: Data preparation started -----------------------');
                    
                    if isfolder(strcat(cd_fullpath, '/PATCH_1'))
                       elements_struct = dir(cd_fullpath); % list all elements in the processing folder
                       elements_cell = struct2cell(elements_struct); % convert from structure to cell
                       elements_cell_name = elements_cell(1,3:end); %take only the file names
                       logic_stamps = contains(elements_cell_name, 'StaMPS'); % find elements with name StaMPS
                       logic_patch = contains(elements_cell_name, 'PATCH_'); % find folder(s) PATCH
                       logic_calamp = contains(elements_cell_name, 'calamp'); % find elements with name calamp
                       logic_len = contains(elements_cell_name, 'len'); % find elements with name len
                       logic_parms = contains(elements_cell_name, 'parms.mat'); % find elements with name parms.mat
                       logic_patch_list = contains(elements_cell_name, 'patch.list'); % find elements with name patch.list
                       logic_processor = contains(elements_cell_name, 'processor.txt'); % find elements with name processor.txt
                       logic_psparms = contains(elements_cell_name, 'ps_parms_initial.log'); % find elements with name ps_parms_initial.log
                       logic_psc = contains(elements_cell_name, 'psc'); % find elements with name psc
                       logic_rsc = contains(elements_cell_name, 'rsc.txt'); % find elements with name rsc.txt
                       logic_selpsc = contains(elements_cell_name, 'selpsc.txt'); % find elements with name selpsc.txt
                       logic_width = contains(elements_cell_name, 'width.txt'); % find elements with name width.txt
                       logic_export = contains(elements_cell_name, 'EXPORT'); % find folder with name EXPORT
                       logic_all = logic_stamps + logic_patch + logic_calamp + logic_len + logic_parms ...
                           + logic_patch_list + logic_processor + logic_psparms + logic_psc + logic_rsc + logic_selpsc + logic_width + logic_export;
                       for i = 1:length(logic_all)
                           if logic_all(i) == 0
                               builtin('delete', (char(strcat(cd_fullpath, '/', elements_cell_name(i))))); % delete the previous-execution elements
                           end
                       end
                    end

                    if tropo_correction_enabled % TRAIN correction is effectively enabled
                        if isunix
    train_path = which('APS_CONFIG.sh');
else
    train_path = '';   % Windows: TRAIN on MATLABPATH, no shell config to source
end
                        source_train = [source space train_path];
                        if isunix
    system(strjoin({source_stamps, source_train, source_snap}, ';'));
else
    mt_prep_snap_status = system([which('mt_prep_snap.bat') ' ' master_date ' ' s2s_export_path ' ' amplitude_threshold]);
    if mt_prep_snap_status ~= 0
        error('PHASE_StaMPS:mtPrepSnapFailed', ...
              'mt_prep_snap failed (exit code %d). Check the Command Window above for the cause (commonly: a previous MATLAB session is still holding files in PATCH_* — close it and retry).', ...
              mt_prep_snap_status);
    end
end % source all the softwares and prepare the data
                    else
                        if isunix
    system(strjoin({source_stamps, source_snap}, ';'));
else
    mt_prep_snap_status = system([which('mt_prep_snap.bat') ' ' master_date ' ' s2s_export_path ' ' amplitude_threshold]);
    if mt_prep_snap_status ~= 0
        error('PHASE_StaMPS:mtPrepSnapFailed', ...
              'mt_prep_snap failed (exit code %d). Check the Command Window above for the cause (commonly: a previous MATLAB session is still holding files in PATCH_* — close it and retry).', ...
              mt_prep_snap_status);
    end
end % source all the softwares and prepare the data
                    end
                    
                    if isfolder(strcat(cd_fullpath, '/EXPORT')) == 0
                        mkdir('EXPORT');
                    end
                
                    updateOutput(app, '----------------------- STEP 1: Data preparation finished -----------------------');
                
                else 
                    
                    if tropo_correction_enabled % TRAIN correction is effectively enabled
                        train_path = which('APS_CONFIG.sh');
                        source_train = [source space train_path];
                        if isunix
    system(strjoin({source_stamps, source_train}, ';'));
else
    % Windows: when train_flag==0 reaches here, Change #1 has already
    % verified TRAIN is on MATLABPATH; no shell config to source.
end % source all the softwares and prepare the data
                    else
                        if isunix
    system(source_stamps);
else
    % Environment comes from self-bootstrapping .bat shim
end % source all the softwares and prepare the data
                    end

                    updateOutput(app, '---------------------- STEP1: Data preparation skipped -----------------------');
                
                end
                
                % Safely delete previous export files if they exist
                csv_path = fullfile(cd_fullpath, 'EXPORT', [export_name, '.csv']);
                if exist(csv_path, 'file') == 2
                    builtin('delete', csv_path);
                end
                
                xlsx_path = fullfile(cd_fullpath, 'EXPORT', [export_name, '.xlsx']);
                if exist(xlsx_path, 'file') == 2
                    builtin('delete', xlsx_path);
                end
                
                %% ---------------- DEFINITION OF THE REQUIRED VARIABLES ------------------
                
                % INPUT VARIABLES
                D = datetime(year_0,month_0,day_0,0,0,0);  % first date of the time series
                time_zero_nf = convertTo(D, 'yyyymmdd'); % date conversion
                time_zero = convertTo(D, 'datenum'); % date conversion
                
                % STAMPS PARAMETERS DEFINITION
                % Step 1 - Load Data
                setparm('heading', heading); % satellite heading wrt north (deg)
                setparm('n_cores', n_cores); % n° of cores to be used for the processing
                % Step 2 - Estimate Phase Noise
                setparm('max_topo_err', max_topo_err); % max uncorrelated DEM error (m)
                setparm('filter_grid_size', filter_grid_size); % pixel size of resampling grid
                setparm('filter_weighting', filter_weighting); % weighting scheme
                setparm('gamma_max_iterations', gamma_max_iterations); % maximum number of iterations
                setparm('gamma_change_convergence', gamma_change_convergence); % threshold for change in mean value of coherence
                setparm('gamma_stdev_reject', gamma_stdev_reject); 
                setparm('quick_est_gamma_flag', quick_est_gamma_flag); % quick estimation of gamma (coherence)
                setparm('small_baseline_flag', small_baseline_flag);
                setparm('clap_win', clap_win); % CLAP filter window size
                setparm('clap_alpha', clap_alpha); % CLAP filter alpha term
                setparm('clap_beta', clap_beta); % CLAP filter beta term
                setparm('clap_low_pass_wavelength', clap_low_pass_wavelength); % CLAP filter spatial wavelength cutoff (m)
                % Step 3 - PS Selection
                setparm('select_method', select_method); % selection method for pixels with random phase, alternative 'DENSITY'
                setparm('percent_rand', percent_rand); % maximum acceptable percentage, alternative 'density_rand'
                % Step 4 - PS Weeding
                setparm('weed_time_win', weed_time_win); % smoothing window (days)
                setparm('weed_standard_dev', weed_standard_dev); % threshold standard deviation
                setparm('weed_neighbours', weed_neighbours); % flag for proximity weeding
                setparm('weed_zero_elevation', weed_zero_elevation); % flag for zero elevation weeding
                setparm('weed_max_noise', weed_max_noise); % threshold for the maximum noise allowed for a pixel
                % Step 5 - Phase Correction
                setparm('merge_resample_size', merge_resample_size); % coarser posting to resample to (m), 0 = no resampling is applied
                setparm('merge_standard_dev', merge_standard_dev); % threshold standard deviation for resampling
                % Step 6 - Phase Unwrapping
                setparm('unwrap_grid_size', unwrap_grid_size); % resampling grid spacing
                setparm('unwrap_gold_n_win', unwrap_gold_n_win); % window size for Goldstein filter
                setparm('unwrap_time_win', unwrap_time_win); % smoothing window for phase noise distribution (days)
                setparm('unwrap_method', unwrap_method); % unwrapping method
                setparm('unwrap_gold_alpha', unwrap_gold_alpha); % value of alpha for Goldstein filter
                setparm('unwrap_alpha', unwrap_alpha); 
                setparm('unwrap_spatial_cost_func_flag', unwrap_spatial_cost_func_flag);
                setparm('unwrap_prefilter_flag', unwrap_prefilter_flag); % prefilter phase before unwrapping to reduce noise
                setparm('unwrap_patch_phase', unwrap_patch_phase); % use the patch phase of step 3 as prefiltered phase
                setparm('unwrap_la_error_flag', unwrap_la_error_flag); % flag for look angle
                setparm('unwrap_hold_good_values', unwrap_hold_good_values);
                setparm('subtr_tropo', subtr_tropo); % subtract tropospheric effect (TRAIN)
                setparm('tropo_method', tropo_method); % method for tropospheric correction (TRAIN)
                setparm('select_reest_gamma_flag', select_reest_gamma_flag);
                setparm('drop_ifg_index', drop_ifg_index); % remove selected interferograms
                % Step 7 - Estimate Spatially Correlated Look Angle Error
                setparm('scla_deramp', scla_deramp); % flag for phase ramp estimation
                setparm('scla_method', scla_method); % scla method
                setparm('scla_drop_index', scla_drop_index); % remove selected interferograms from step 7 & 8
                % Step 8 - Atmospheric Filtering
                setparm('scn_wavelength', scn_wavelength); % spatial filter
                setparm('scn_time_win', scn_time_win); % temporal filter (days)
                setparm('scn_kriging_flag', scn_kriging_flag);
                % Plots
                setparm('ref_centre_lonlat', ref_centre_lonlat); % deg coordinates of the centre of AOI
                setparm('ref_radius', ref_radius); % radius in m
                setparm('ref_velocity', ref_velocity); % reference velocity for plot
                setparm('plot_s', plot_s); % dimension of PS pixels in the plot
                
                % UPDATE PARMS.MAT IN CASE OF NANs
                heading1_tmp = heading;
                lambda_tmp = lambda;
                load('parms.mat');
                if isnan(heading)
                    heading = heading_tmp;
                end
                if isnan(lambda)
                    lambda = lambda_tmp;
                end
                save('parms.mat', 'heading', 'lambda', '-append');
                clear heading_tmp
                clear lambda_tmp
                
                
                %% -------------------------- RUN STAMPS ----------------------------------
                
                updateOutput(app, '----------------------- STEP 2: StaMPS processing started -----------------------');

                % begin Windows StaMPS step-6 preflight
                % The old Windows ps_unwrap branch calls uw_nosnaphu.  Its
                % sparse-graph solver can loop forever without raising an error,
                % which looks exactly like a frozen PHASE app.  Refuse that stale
                % implementation and verify the maintained snaphu path up front.
                if contains(ph_output, 'unwrapped') && stamps_last_step >= 6
                    ps_unwrap_impl = which('ps_unwrap');
                    if isempty(ps_unwrap_impl)
                        error('PHASE_StaMPS:psUnwrapMissing', ...
                            ['ps_unwrap.m was not found. Check the StaMPS installation ', ...
                             'folder selected in PHASE: %s'], installation_folder);
                    end

                    if ispc
                        ps_unwrap_src = fileread(ps_unwrap_impl);
                        old_unwrap_call = regexp(ps_unwrap_src, ...
                            '(?m)^\s*\[ph_uw_some\]\s*=\s*uw_nosnaphu\s*\(', 'once');
                        if ~isempty(old_unwrap_call)
                            error('PHASE_StaMPS:staleWindowsPsUnwrap', ...
                                ['PHASE resolved an obsolete Windows ps_unwrap.m that is known ', ...
                                 'to loop indefinitely during step 6: %s. Update/reinstall the ', ...
                                 'pyccino/StaMPS master fork and make that folder the StaMPS ', ...
                                 'installation folder in PHASE.'], ps_unwrap_impl);
                        end

                        uw_stat_impl = which('uw_stat_costs');
                        if isempty(uw_stat_impl)
                            error('PHASE_StaMPS:uwStatCostsMissing', ...
                                'uw_stat_costs.m was not found below the selected StaMPS installation.');
                        end
                        uw_stat_src = fileread(uw_stat_impl);
                        unix_redirect_call = regexp(uw_stat_src, ...
                            '(?m)^\s*cmdstr\s*=.*>&\s*snaphu\.log', 'once');
                        if ~isempty(unix_redirect_call)
                            error('PHASE_StaMPS:staleWindowsUwStatCosts', ...
                                ['PHASE resolved an obsolete uw_stat_costs.m containing a ', ...
                                 'Unix-only snaphu command: %s. Update/reinstall the ', ...
                                 'pyccino/StaMPS master fork.'], uw_stat_impl);
                        end

                        [snaphu_status, snaphu_where] = dos('where snaphu');
                        if snaphu_status ~= 0 || isempty(strtrim(snaphu_where))
                            expected_snaphu = fullfile(installation_folder, ...
                                'external', 'snaphu', 'bin', 'snaphu.exe');
                            error('PHASE_StaMPS:snaphuMissing', ...
                                ['snaphu.exe is not available on PATH, so StaMPS step 6 ', ...
                                 'cannot unwrap safely on Windows. Expected installer output: ', ...
                                 '%s. Re-run the PHASE installer or restore the native StaMPS ', ...
                                 'binaries before retrying.'], expected_snaphu);
                        end

                        resolved_snaphu = regexprep(strtrim(snaphu_where), '[\r\n]+', '; ');
                        updateOutput(app, ['Step 6 preflight: ps_unwrap = ' ps_unwrap_impl]);
                        updateOutput(app, ['Step 6 preflight: snaphu = ' resolved_snaphu]);
                    end

                    updateOutput(app, ['Step 6 preflight passed. Phase unwrapping can take ', ...
                        'a long time; progress is printed in the MATLAB Command Window as ', ...
                        '''Processing IFG x of y''.']);
                    drawnow;
                    clear ps_unwrap_impl ps_unwrap_src old_unwrap_call uw_stat_impl
                    clear uw_stat_src unix_redirect_call snaphu_status snaphu_where
                    clear expected_snaphu resolved_snaphu
                end
                % end Windows StaMPS step-6 preflight
                
                if contains(ph_output, 'unwrapped')
                    % TRAIN is usable only when both controls request correction.
                    if tropo_correction_enabled
                        if stamps_first_step <= 6
                            % Guard: a stale tca<psver>.mat from a different tropo_method makes step 6
                            % (ps_unwrap with subtr_tropo='y') fail looking for ph_tropo_<method>.
                            % Drop it so the correct correction is recomputed by this run.
                            if exist('psver.mat', 'file') == 2
                                psver_tmp = load('psver.mat');
                                tca_file = sprintf('tca%d.mat', psver_tmp.psver);
                                if exist(tca_file, 'file') == 2
                                    method_field = ['ph_tropo_' strrep(tropo_method, 'a_', '')];
                                    if ~ismember(method_field, who('-file', tca_file))
                                        delete(tca_file);
                                        updateOutput(app, ['Removed stale ' tca_file ' (incompatible with tropo_method=' tropo_method ').']);
                                    end
                                end
                                clear psver_tmp
                            end
                            stamps(stamps_first_step,6); % execute StaMPS steps from first step to 6
                            
                            if contains(tropo_method, 'a_linear')
                                
                                aps_linear; % to apply the TRAIN tropospheric correction
                                
                            elseif contains(tropo_method, 'a_gacos')
                                
                                mkdir(strcat(cd, '/GACOS'));
                                setparm_aps('gacos_datapath', strcat(cd, '/GACOS'));
                                setparm_aps('UTC_sat', utc_time);
                                aps_weather_model('gacos', 0, 0);
                    
                                if exist('gacos_maps', 'dir') == 0
    
                                    % Clearer instructions regarding multiple files
                                    instructions = ['Go to www.gacos.net and request atmospheric delay maps using the data in "GACOS_download_info.txt". ', ...
                                                    'GACOS limits requests to 20 dates. If you have more, make multiple requests. ', ...
                                                    'Place ALL the downloaded compressed folders (.tar.gz) directly into the "GACOS" folder. ', ...
                                                    'Do not extract them. Once all files are in place, press "Continue" in the dialog box to resume.'];
                                    updateOutput(app, instructions);
                                        
                                    % --- Manual GACOS download (no debug 'keyboard') ---
                                    % Open the GACOS website and the destination folder, then BLOCK on a
                                    % modal dialog until the user has placed the .tar.gz archives. Replaces
                                    % the old `keyboard` so the run never drops into MATLAB debug mode.
                                    gacosFolder = fullfile(cd, 'GACOS');
                                    try, web('http://www.gacos.net', '-browser'); catch, end
                                    try, winopen(gacosFolder); catch, end
                                    while true
                                        gacos_choice = gacosDownloadDialog();
                                        if strcmp(gacos_choice, 'Cancel')
                                            updateOutput(app, 'GACOS download cancelled by user. No atmospheric correction applied.');
                                            if exist('cd_fullpath', 'var') && ~isempty(cd_fullpath) && isfolder(cd_fullpath)
                                                cd(cd_fullpath);
                                            end
                                            app.PreprocessingstatusLamp.Color = [1, 0, 0];
                                            return
                                        end
                                        if isempty(dir(fullfile(gacosFolder, '*.tar.gz')))
                                            uialert(app.UIFigure, ...
                                                'No .tar.gz files found in the GACOS folder yet. Place them and press Continue.', ...
                                                'GACOS files missing');
                                        else
                                            break
                                        end
                                    end
                                    
                                    % Define main paths
                                    gacosFolder = fullfile(cd, 'GACOS');
                                    gacosMapsFolder = fullfile(cd, 'gacos_maps');
                                    mkdir(gacosMapsFolder);
                                
                                    % Find all .tar.gz files injected by the user
                                    gzFiles = dir(fullfile(gacosFolder, '*.tar.gz'));
                                    
                                    if isempty(gzFiles)
                                        updateOutput(app, 'Warning: No .tar.gz files found in the GACOS folder! GACOS corrections will fail.');
                                    else
                                        updateOutput(app, sprintf('Found %d GACOS .tar.gz file(s). Extracting and sorting...', length(gzFiles)));
                                        
                                        % 1. Extract all archives
                                        for k = 1:length(gzFiles)
                                            gzFilePath = fullfile(gacosFolder, gzFiles(k).name);
                                            tempUnzipDir = fullfile(gacosFolder, sprintf('temp_unzip_%d', k));
                                            
                                            % gunzip the .tar.gz
                                            gunzip(gzFilePath, tempUnzipDir);
                                            
                                            % untar the resulting .tar directly into the shared gacos_maps folder
                                            tarFiles = dir(fullfile(tempUnzipDir, '*.tar'));
                                            if ~isempty(tarFiles)
                                                tarFilePath = fullfile(tempUnzipDir, tarFiles(1).name);
                                                untar(tarFilePath, gacosMapsFolder);
                                            end
                                            
                                            % Cleanup temp folder and original archive
                                            rmdir(tempUnzipDir, 's');
                                            builtin('delete', gzFilePath);
                                        end
                                        
                                        % 2. Distribute the extracted files into their respective date folders
                                        % We specifically look for .ztd files, making this immune to hidden OS files
                                        ztdFiles = dir(fullfile(gacosMapsFolder, '*.ztd'));
                                        
                                        for k = 1:length(ztdFiles)
                                            % Extract the date from the filename (e.g., '20200305' from '20200305.ztd')
                                            [~, dateStr, ~] = fileparts(ztdFiles(k).name);
                                            
                                            ztdSource = fullfile(gacosMapsFolder, [dateStr, '.ztd']);
                                            rscSource = fullfile(gacosMapsFolder, [dateStr, '.ztd.rsc']);
                                            
                                            ztdDest = fullfile(gacosFolder, dateStr, [dateStr, '.ztd']);
                                            rscDest = fullfile(gacosFolder, dateStr, [dateStr, '.ztd.rsc']);
                                            
                                            % Only move if TRAIN successfully created the target date folder
                                            if exist(fullfile(gacosFolder, dateStr), 'dir') == 7
                                                movefile(ztdSource, ztdDest);
                                                if exist(rscSource, 'file') == 2
                                                    movefile(rscSource, rscDest);
                                                end
                                            else
                                                updateOutput(app, ['Warning: Target folder GACOS/', dateStr, ' does not exist. Skipping file.']);
                                            end
                                        end
                                        
                                        updateOutput(app, 'GACOS maps successfully extracted and allocated.');
                                    end
                                end
                                    
                                setparm_aps('lambda', lambda, 1);
                                setparm_aps('heading', heading, 1);
                                % TRAIN derives its InSAR output path from pwd.  Force the
                                % processing root so tca<psver>.mat cannot be written elsewhere.
                                cd(cd_fullpath);
                                aps_weather_model('gacos', 3, 3);
                                cd(cd_fullpath);
                                
                            end

                            % begin TRAIN/TCA handoff guard
                            % ps_calc_scla (StaMPS step 7) loads ./tca<psver> as soon as
                            % subtr_tropo='y'.  Validate TRAIN's output here so a missing or
                            % incompatible correction never reaches that opaque load() call.
                            psver_tca = load(fullfile(cd_fullpath, 'psver.mat'), 'psver');
                            if strcmpi(strtrim(small_baseline_flag), 'y')
                                tca_name = sprintf('tca_sb%d.mat', psver_tca.psver);
                            else
                                tca_name = sprintf('tca%d.mat', psver_tca.psver);
                            end
                            tca_path = fullfile(cd_fullpath, tca_name);
                            tca_field = ['ph_tropo_' regexprep(strtrim(tropo_method), '^a_', '')];
                            if exist(tca_path, 'file') ~= 2
                                error('PHASE_StaMPS:gacosTcaMissing', ...
                                    ['TRAIN finished without creating %s. StaMPS step 7 cannot ', ...
                                     'apply tropo_method=%s without this file. aps_weather_model_InSAR ', ...
                                     'resolved to: %s'], tca_path, tropo_method, ...
                                     which('aps_weather_model_InSAR'));
                            end
                            tca_vars = who('-file', tca_path);
                            if ~ismember(tca_field, tca_vars)
                                error('PHASE_StaMPS:gacosTcaInvalid', ...
                                    ['%s exists, but does not contain %s for tropo_method=%s. ', ...
                                     'Restart PHASE at StaMPS step 6 to rebuild the correction.'], ...
                                     tca_path, tca_field, tropo_method);
                            end
                            updateOutput(app, ['TRAIN correction ready for StaMPS step 7: ' tca_path]);
                            clear psver_tca tca_name tca_path tca_field tca_vars
                            % end TRAIN/TCA handoff guard
                            
                            stamps(7,7); % execute StaMPS step 7
                            stamps(6,stamps_last_step); % subtract the computed corrections before the phase unwrapping
                        else
                            % begin resumed TRAIN/TCA handoff guard
                            % A run resumed at step 7 used to assume that tca<psver>.mat was
                            % already present.  Rebuild a missing/incompatible GACOS correction
                            % from the staged .ztd maps before entering StaMPS.
                            cd(cd_fullpath);
                            psver_tca = load(fullfile(cd_fullpath, 'psver.mat'), 'psver');
                            if strcmpi(strtrim(small_baseline_flag), 'y')
                                tca_name = sprintf('tca_sb%d.mat', psver_tca.psver);
                            else
                                tca_name = sprintf('tca%d.mat', psver_tca.psver);
                            end
                            tca_path = fullfile(cd_fullpath, tca_name);
                            tca_field = ['ph_tropo_' regexprep(strtrim(tropo_method), '^a_', '')];
                            tca_vars = {};
                            if exist(tca_path, 'file') == 2
                                tca_vars = who('-file', tca_path);
                            end

                            if (exist(tca_path, 'file') ~= 2 || ~ismember(tca_field, tca_vars)) && ...
                                    contains(tropo_method, 'a_gacos')
                                gacosFolder = fullfile(cd_fullpath, 'GACOS');
                                gacosZtd = dir(fullfile(gacosFolder, '**', '*.ztd'));
                                if isempty(gacosZtd)
                                    error('PHASE_StaMPS:gacosMapsMissing', ...
                                        ['Cannot rebuild %s: no GACOS .ztd maps were found below %s. ', ...
                                         'Restart PHASE at StaMPS step 6 to download/import them.'], ...
                                         tca_path, gacosFolder);
                                end
                                updateOutput(app, ['Rebuilding missing GACOS correction before StaMPS step 7: ' tca_path]);
                                setparm_aps('gacos_datapath', gacosFolder);
                                setparm_aps('UTC_sat', utc_time);
                                setparm_aps('lambda', lambda, 1);
                                setparm_aps('heading', heading, 1);
                                cd(cd_fullpath);
                                aps_weather_model('gacos', 3, 3);
                                cd(cd_fullpath);
                            end

                            if exist(tca_path, 'file') ~= 2
                                error('PHASE_StaMPS:gacosTcaMissing', ...
                                    ['StaMPS step 7 needs %s for tropo_method=%s, but TRAIN did ', ...
                                     'not create it. aps_weather_model_InSAR resolved to: %s'], ...
                                     tca_path, tropo_method, which('aps_weather_model_InSAR'));
                            end
                            tca_vars = who('-file', tca_path);
                            if ~ismember(tca_field, tca_vars)
                                error('PHASE_StaMPS:gacosTcaInvalid', ...
                                    ['%s does not contain %s. Restart PHASE at StaMPS step 6 ', ...
                                     'to rebuild the selected atmospheric correction.'], ...
                                     tca_path, tca_field);
                            end
                            updateOutput(app, ['TRAIN correction ready for resumed StaMPS processing: ' tca_path]);
                            clear psver_tca tca_name tca_path tca_field tca_vars gacosFolder gacosZtd
                            % end resumed TRAIN/TCA handoff guard

                            stamps(stamps_first_step, stamps_last_step);
                            stamps(6,stamps_last_step); % subtract the computed corrections before the phase unwrapping
                        end
                    else
                        % begin TRAIN-degradation GUI notice (surfaces the otherwise-silent
                        % subtr_tropo='n' reset; mirrors the Command Window warning above so
                        % the user is not left thinking the requested correction actually ran).
                        if ~train_available && strcmpi(subtr_tropo, 'y')
                            updateOutput(app, ['NOTE: TRAIN was not found on the MATLAB path, so the requested ', ...
                                'tropospheric correction (subtr_tropo=y, tropo_method=', tropo_method, ') is being ', ...
                                'SKIPPED and subtr_tropo is forced to ''n'' for this run. To enable it, run ', ...
                                'which(''aps_weather_model'') in MATLAB: if it is empty, add TRAIN with ', ...
                                'addpath(genpath(''<TRAIN>/matlab'')); savepath, then press Start again. ', ...
                                'For tropo_method=a_gacos also install GMT and make sure ''gmt --version'' works.']);
                        end
                        % end TRAIN-degradation GUI notice
                        setparm('subtr_tropo', 'n');
                        stamps(stamps_first_step, stamps_last_step); % execute the complete range selected in the GUI
                    end
                
                elseif contains(ph_output, 'wrapped')
                    ref_centre_lonlat = ref_centre_lonlat_w;
                    stamps(stamps_first_step,5)
                end
                
                updateOutput(app, '----------------------- STEP 2: StaMPS processing finished -----------------------');
                
                
                %% ----------------------- EXPORT THE RESULTS -----------------------------
                
                if stamps_last_step > 6 && contains(ph_output, 'unwrapped')
                
                    updateOutput(app, '----------------------- STEP 3: Displacement time series export started -----------------------');

                    % LEGACY EXPORT: produces a single XLSX/CSV with every
                    % PS for the AOI. Granular point selection on a basemap
                    % is now handled by the TS Points tab (uses
                    % ts_export_picker on top of ps_plot_ts_<vt>.mat).
                    %
                    % Historically this block opened an interactive figure
                    % via ps_plot(...,'ts'), paused on `keyboard`, and asked
                    % the user to click a centre + radius before continuing.
                    % That manual step has been removed: it stalled the
                    % run when the user didn't notice the K>> prompt, and
                    % the TS Points tab covers point-level export better.
                    % ps_plot needs to be called twice: once with 'ts' to produce
                    % ps_plot_ts_<vt>.mat (the per-PS time-series matrix used
                    % below), and once with -1 to produce ps_plot_v[-d]a-o.mat
                    % (the per-PS velocity vector). The 'ts' call opens a
                    % background figure even with BACKGROUND=1; we capture and
                    % delete it so the run stays non-interactive.
                    if tropo_correction_enabled % TRAIN tropo correction included
                        load parms.mat;
                        fig_before = findall(0, 'Type', 'figure');
                        evalc("ps_plot('v-dao', tropo_method, 'ts', 1)");
                        new_figs = setdiff(findall(0, 'Type', 'figure'), fig_before);
                        if ~isempty(new_figs); delete(new_figs); end
                        ps_plot('v-dao', tropo_method, -1);   % write ps_plot_v-dao.mat
                        load ps_plot_v-dao.mat;
                        loaded_ps_ts = load('ps_plot_ts_v-dao.mat');
                        new_days_name = 'day_var';
                        eval([new_days_name ' = loaded_ps_ts.day;']);
                        load('ps_plot_ts_v-dao.mat', 'bperp', 'ifg_list', 'lambda', 'lonlat', 'master_day', 'n_ps', 'ph_mm', 'ref_ps', 'unwrap_ifg_index');
                    else % no TRAIN
                        load parms.mat;
                        fig_before = findall(0, 'Type', 'figure');
                        evalc("ps_plot('v-do', 'ts', 1)");
                        new_figs = setdiff(findall(0, 'Type', 'figure'), fig_before);
                        if ~isempty(new_figs); delete(new_figs); end
                        ps_plot('v-do', -1);                  % write ps_plot_v-do.mat
                        load ps_plot_v-do.mat;
                        loaded_ps_ts = load('ps_plot_ts_v-do.mat');
                        new_days_name = 'day_var';
                        eval([new_days_name ' = loaded_ps_ts.day;']);
                        load('ps_plot_ts_v-do.mat', 'bperp', 'ifg_list', 'lambda', 'lonlat', 'master_day', 'n_ps', 'ph_mm', 'ref_ps', 'unwrap_ifg_index');
                    end
                    % Include all PS (was: ismember against user-clicked lon2/lat2)
                    ind = true(size(lonlat,1),1);
                    displ = ph_disp(ind);
                    disp_ts = ph_mm(ind,:);
                    
                    time_days = ((transpose(day_var))-time_zero);  % time and displacement vector with master
                    time_master = master_day - time_zero;
                    for i=1:length(time_days)
                        if time_days(i) < time_master
                            disp_ts_temp(:,i) = disp_ts(:,i);
                            time_days_temp(i) = time_days(i);
                            n = i;
                        else
                            disp_ts_temp(:,n+1) = zeros(size(disp_ts,1),1);
                            time_days_temp(n+1) = time_master;
                            disp_ts_temp(:,i+1) = disp_ts(:,i);
                            time_days_temp(i+1) = time_days(i);
                        end
                    end
                    disp_ts = disp_ts_temp;
                    clear disp_ts_temp
                    time_days = time_days_temp;
                    clear time_days_temp
                    
                    export_res = [lonlat(ind,1) lonlat(ind,2) displ disp_ts];
                
                    % TABLE CREATION AND EXPORT
                    point = (1:size(export_res, 1))';  % export first part of the table
                    lon = export_res(:,1);
                    lat = export_res(:,2);
                    vel = export_res(:,3);
                    infos_points = table(point, lon, lat, vel);
                    writetable(infos_points, strcat(cd_fullpath, '/EXPORT/', export_name, '.xlsx'), 'Range', 'A2:D500000');
                    save('PS_index.mat', 'ind');
                 
                    daily_displ = export_res(:,4:end);  % export second part of the table
                    table_p2 = [time_days; daily_displ];
                    displacements = table(table_p2);
                    writetable(displacements, strcat(cd_fullpath, '/EXPORT/', export_name, '.xlsx'), 'Range', 'E1:FZ500000');
                
                    displ_table_all = readtable(strcat('./EXPORT/', export_name, '.xlsx'));
                    writetable(displ_table_all, strcat('./EXPORT/', export_name, '.csv'), 'WriteMode', 'overwrite');
                
                    updateOutput(app, '----------------------- STEP 3: Displacement time series export finished -----------------------');
                
                elseif contains(ph_output, 'wrapped')

                    updateOutput(app, '----------------------- STEP 3: Displacement time series export started -----------------------');

                    load psver.mat

                    psinfo_name = strcat('./ps', num2str(psver), '.mat');
                    phase_name = strcat('./rc', num2str(psver), '.mat');
                    
                    loaded_day = load(psinfo_name, 'day');   % load the day variable and name change
                    new_days_name_w = 'day_var_w';
                    eval([new_days_name_w ' = loaded_day.day;']);
                    
                    load(psinfo_name, 'lonlat');       % load the lonlat variable
                    load(psinfo_name, 'master_day');   % load the master_day variable
                    load(phase_name);                  % load the variable with the phase values
                    
                    ph_all = ph_rc;         % wrapped complex phase for all PSs
                    ph_w = angle(ph_all);   % wrapped phase for all PSs
                    
                    % Find PSs inside the given radius
                    radius_size_deg = ref_radius_w / (111300 * cosd(ref_centre_lonlat_w(2)));                                   % Convert radius from meters to degrees (approximate conversion)
                    distances_w = sqrt((lonlat(:,1) - ref_centre_lonlat_w(1)).^2 + (lonlat(:,2) - ref_centre_lonlat_w(2)).^2);   % Calculate distances between each point and the reference center
                    indices_w = find(distances_w <= radius_size_deg);                                                            % Find indices of points inside the buffer
                    ph_w_ts = ph_w(indices_w,:);
                    
                    % Data preparation for export
                    time_days = ((transpose(day_var_w))-time_zero);  % time and displacement vector with master
                    % time_master = master_day - time_zero;
                    % for i=1:length(time_days)
                    %     if time_days(i) < time_master
                    %         ph_w_ts_temp(:,i) = ph_w_ts(:,i);
                    %         time_days_temp(i) = time_days(i);
                    %         n = i;
                    %     else
                    %         ph_w_ts_temp(:,n+1) = zeros(size(ph_w_ts,1),1);
                    %         time_days_temp(n+1) = time_master;
                    %         ph_w_ts_temp(:,i+1) = ph_w_ts(:,i);
                    %         time_days_temp(i+1) = time_days(i);
                    %     end
                    % end
                    % ph_w_ts = ph_w_ts_temp;
                    % clear ph_w_ts_temp
                    % time_days = time_days_temp;
                    % clear time_days_temp
                    
                    export_res = [lonlat(indices_w,1) lonlat(indices_w,2) ph_w_ts];
                    
                    % TABLE CREATION AND EXPORT
                    point = (1:size(export_res, 1))';  % export first part of the table
                    lon = export_res(:,1);
                    lat = export_res(:,2);
                    infos_points = table(point, lon, lat);
                    writetable(infos_points, strcat(cd_fullpath, '/EXPORT/', export_name, '_phW.xlsx'), 'Range', 'A2:C500000');
                    
                    daily_displ = export_res(:,3:end);  % export second part of the table
                    table_p2 = [time_days; daily_displ];
                    displacements = table(table_p2);
                    writetable(displacements, strcat(cd_fullpath, '/EXPORT/', export_name, '_phW.xlsx'), 'Range', 'D1:FZ500000');
                    
                    displ_table_all = readtable(strcat('./EXPORT/', export_name, '_phW.xlsx'));
                    writetable(displ_table_all, strcat('./EXPORT/', export_name, '_phW.csv'), 'WriteMode', 'overwrite');

                    updateOutput(app, '----------------------- STEP 3: Displacement time series export finished -----------------------');

                end


                %% ------------------ EXPORT ATMOSPHERIC CORRECTION -----------------------
                
                if stamps_last_step > 6 && tropo_correction_enabled && contains(ph_output, 'unwrapped')
                
                    updateOutput(app, '----------------------- STEP 4: Atmosphere time series export started -----------------------');
                
                    atm_method_name = tropo_method;
                    atm_method_name(1:2) = [];
                    load('tca2.mat', strcat('ph_tropo_', atm_method_name));
                    load('ps2.mat', 'lonlat');
                    load('PS_index.mat', 'ind');
                    atm_ph = eval(strcat('ph_tropo_', atm_method_name));
                
                    lonlat_AOI = lonlat(ind,:);
                    atm_ph_AOI = atm_ph(ind,:);
                    
                    % PHASE TO DISPLACEMENT CONVERSION OF THE ATMOSPHERIC DELAY
                    atm_mm_AOI = atm_ph_AOI .* (1000 * lambda / (-4 * pi));  % mm in LOS direction
                
                    save(strcat('Atmosphere_', atm_method_name, '_AOI_PS.mat'), 'lonlat_AOI', 'atm_mm_AOI');
                
                    % TABLE CREATION AND EXPORT
                    infos_points_tropo = table(point, lon, lat);
                    writetable(infos_points_tropo, strcat(cd_fullpath, '/EXPORT/', export_name, '_ATMOSPHERE.xlsx'), 'Range', 'A2:C500000');
                
                    table_p2_tropo = [time_days; atm_mm_AOI]; % export second part of the table
                    atm_delay = table(table_p2_tropo);
                    writetable(atm_delay, strcat(cd_fullpath, '/EXPORT/', export_name, '_ATMOSPHERE.xlsx'), 'Range', 'D1:FZ500000');
                
                    displ_table_atm_all = readtable(strcat('./EXPORT/', export_name, '_ATMOSPHERE.xlsx'));
                    writetable(displ_table_atm_all, strcat('./EXPORT/', export_name, '_ATMOSPHERE.csv'), 'WriteMode', 'overwrite');
                
                    updateOutput(app, '----------------------- STEP 4: Atmosphere time series export finished -----------------------');
                
                end

                %% ----------------------------------------------------

                % Display a message when the script is done
                updateOutput(app, 'StaMPS processing completed.');

                % Change lamp color to green (success)
                app.PreprocessingstatusLamp.Color = [0, 1, 0]; % Green

                % Offer to open the TS picker right away. Reaches the
                % picker via the existing OpenTSPickerButtonPushed
                % callback (workdir resolution, auto-gen of
                % ps_plot_ts_<vt>.mat, Mapping Toolbox check are all
                % handled there). Wrapped in try/catch so an older MATLAB
                % without uiconfirm still completes the Start cleanly.
                try
                    full_export_name = app.FilenameEditField.Value;
                catch
                    full_export_name = 'output';
                end
                try
                    choice = uiconfirm(app.UIFigure, ...
                        ['StaMPS processing completed.' newline newline ...
                         'A complete export has been written automatically to the ' ...
                         '''EXPORT/'' folder (' full_export_name '.xlsx and .csv), ' ...
                         'containing every PS with its full displacement time-series.' newline newline ...
                         'Open the interactive TS picker now to also extract per-point ' ...
                         'CSV time-series for a subset of points of interest? Each ' ...
                         'selected point will be saved as EXPORT/' full_export_name ...
                         '_<id>.csv.' newline newline ...
                         'You can always open the picker later from the ''TS Points'' tab.'], ...
                        'Open TS picker', ...
                        'Options', {'Open now', 'Open later'}, ...
                        'DefaultOption', 1, 'CancelOption', 2);
                catch
                    choice = 'Open later';
                end
                if strcmp(choice, 'Open now')
                    try
                        app.TabGroup.SelectedTab = app.TSPointsTab;
                    catch
                        % TabGroup property may have a different name in
                        % future layouts; the picker still works from the
                        % button if the user navigates manually.
                    end
                    try
                        OpenTSPickerButtonPushed(app, []);
                    catch ME
                        uialert(app.UIFigure, ...
                            ['Auto-launch of TS picker failed: ' ME.message newline ...
                             'Click ''Load TS picker into this tab'' on the TS Points tab to retry.'], ...
                            'TS picker auto-launch failed');
                    end
                end

            catch ME

                % Restore the working directory to the StaMPS folder so a
                % subsequent Start run doesn't inherit a stale pwd of e.g.
                % .../PATCH_1 (stamps.m cd's into PATCH_<i> and only restores
                % on the happy path; if anything throws mid-loop, pwd stays
                % there). cd_fullpath was captured at the top of the run.
                try
                    if exist('cd_fullpath', 'var') && ~isempty(cd_fullpath) && isfolder(cd_fullpath)
                        cd(cd_fullpath);
                    end
                catch
                end

                % Persist the complete stack trace.  The UI previously showed only
                % ME.message, which made a Windows native-tool failure impossible to
                % distinguish from a MATLAB/StaMPS source mismatch.
                try
                    phase_error_report = getReport(ME, 'extended', 'hyperlinks', 'off');
                    fprintf(2, '\n%s\n', phase_error_report);
                    phase_error_log = fullfile(pwd, 'PHASE_StaMPS_error.log');
                    phase_error_fid = fopen(phase_error_log, 'w');
                    if phase_error_fid ~= -1
                        fprintf(phase_error_fid, '%s\n', phase_error_report);
                        fclose(phase_error_fid);
                    end
                catch
                    phase_error_log = '';
                end

                % If an error occurs
                if isempty(ME.identifier)
                    updateOutput(app, ['An error occurred during script execution: ' ME.message]);
                else
                    updateOutput(app, ['An error occurred during script execution [' ME.identifier ']: ' ME.message]);
                end
                if ~isempty(phase_error_log)
                    updateOutput(app, ['Full error report saved to: ' phase_error_log]);
                else
                    updateOutput(app, 'Please check each step log file!');
                end

                % Change lamp color to red (error)
                app.PreprocessingstatusLamp.Color = [1, 0, 0]; % Red

            end


        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            
            filename = './input_StaMPS.mat';  % Specify the filename
            
            if exist(filename, 'file') == 2
                data = load(filename);
                
                if data.stamps_preparation == 0   % preparation flag
                    app.datapreparationforStaMPSCheckBox.Value = true;
                elseif data.stamps_preparation == 1
                    app.datapreparationforStaMPSCheckBox.Value = false;
                end
                app.PathEditField_2.Value = data.installation_folder;
                app.PathEditField_3.Value = data.project_path;
                app.ValueEditField.Value = data.amplitude_threshold;
                app.MasterdateDatePicker.Value = datetime(data.master_date, 'InputFormat', 'yyyyMMdd', 'Format', 'dd-MMM-uuuu');
                app.master_date = data.master_date;
                app.FilenameEditField.Value = data.export_name;
                app.DaysEditField.Value = data.time_span;		
                if isfield(data, 'weed_time_win')
                    app.weed_time_winEditField.Value = data.weed_time_win;
                else
                    app.weed_time_winEditField.Value = data.time_span;
                end
                if isfield(data, 'unwrap_time_win')
                    app.unwrap_time_winEditField.Value = data.unwrap_time_win;
                else
                    app.unwrap_time_winEditField.Value = data.time_span;
                end
                if isfield(data, 'scn_time_win')
                    app.scn_time_winEditField.Value = data.scn_time_win;
                else
                    app.scn_time_winEditField.Value = data.time_span;
                end
                app.DateDatePicker.Value = datetime(data.year_0, data.month_0, data.day_0);
                app.TimeEditField.Value = data.utc_time;
                if data.train_flag == 0   % train flag
                    app.TRAINatmosphericcorrectionCheckBox.Value = true;
                elseif data.train_flag == 1
                    app.TRAINatmosphericcorrectionCheckBox.Value = false;
                end
                app.StaMPSfirststepDropDown.Value = data.stamps_first_step;
                app.StaMPSlaststepDropDown.Value = data.stamps_last_step;
                app.NumberofcoresEditField.Value = data.n_cores;
                app.headingEditField.Value = data.heading;
                app.lambdaEditField.Value = data.lambda;
                app.max_topo_errEditField.Value = data.max_topo_err;				
                app.filter_grid_sizeEditField.Value = data.filter_grid_size;			
                app.filter_weightingEditField.Value = data.filter_weighting;		
                app.gamma_max_iterationsEditField.Value = data.gamma_max_iterations;		
                app.gamma_change_convergenceEditField.Value = data.gamma_change_convergence;	
                app.gamma_stdev_rejectEditField.Value = data.gamma_stdev_reject;
                app.quick_est_gamma_flagEditField.Value = data.quick_est_gamma_flag;		
                app.small_baseline_flagEditField.Value = data.small_baseline_flag;
                app.clap_winEditField.Value = data.clap_win;			
                app.clap_alphaEditField.Value = data.clap_alpha;				
                app.clap_betaEditField.Value = data.clap_beta;				
                app.clap_low_pass_wavelengthEditField.Value = data.clap_low_pass_wavelength;
                app.select_methodDropDown.Value = data.select_method;
                if strcmp(data.select_method, 'PERCENT')
                    app.percent_randEditField.Value = data.percent_rand;
                    app.percent_randEditField.Visible = 'on';
                    app.percent_randEditFieldLabel.Visible = 'on';
                    app.density_randEditField.Visible = 'off';
                    app.density_randEditFieldLabel.Visible = 'off';
                elseif strcmp(data.select_method, 'DENSITY')
                    app.denisty_randEditField.Value = data.density_rand;
                    app.percent_randEditField.Visible = 'off';
                    app.percent_randEditFieldLabel.Visible = 'off';
                    app.density_randEditField.Visible = 'on';
                    app.density_randEditFieldLabel.Visible = 'on';
                end
                app.weed_standard_devEditField.Value = data.weed_standard_dev;	
                app.weed_neighboursEditField.Value = data.weed_neighbours;	
                app.weed_zero_elevationEditField.Value = data.weed_zero_elevation;	
                app.weed_max_noiseEditField.Value = data.weed_max_noise;	
                app.merge_resample_sizeEditField.Value = data.merge_resample_size;
                app.merge_standard_devEditField.Value = data.merge_standard_dev;
                app.unwrap_grid_sizeEditField.Value = data.unwrap_grid_size;		
                app.unwrap_gold_n_winEditField.Value = data.unwrap_gold_n_win;				
                app.unwrap_methodEditField.Value = data.unwrap_method;			
                app.unwrap_gold_alphaEditField.Value = data.unwrap_gold_alpha;			
                app.unwrap_alphaEditField.Value = data.unwrap_alpha;
                app.unwrap_spatial_cost_func_flagEditField.Value = data.unwrap_spatial_cost_func_flag;
                app.unwrap_prefilter_flagEditField.Value = data.unwrap_prefilter_flag;
                app.unwrap_patch_phaseEditField.Value = data.unwrap_patch_phase;		
                app.unwrap_la_error_flagEditField.Value = data.unwrap_la_error_flag;			
                app.unwrap_hold_good_valuesEditField.Value = data.unwrap_hold_good_values;
                app.subtr_tropoEditField.Value = data.subtr_tropo;	
                app.tropo_methodEditField.Value = data.tropo_method;		
                app.select_reest_gamma_flagEditField.Value = data.select_reest_gamma_flag;
                app.drop_ifg_indexEditField.Value = data.drop_ifg_index;
                app.scla_derampEditField.Value = data.scla_deramp;
                app.scla_methodEditField.Value = data.scla_method;	
                app.scla_drop_indexEditField.Value = data.scla_drop_index;
                app.scn_wavelengthEditField.Value = data.scn_wavelength;
                app.scn_kriging_flagEditField.Value = data.scn_kriging_flag;
                app.ref_centre_lonEditField.Value = data.ref_centre_lonlat(1);
                app.ref_centre_latEditField.Value = data.ref_centre_lonlat(2);
                app.ref_radiusEditField.Value = data.ref_radius;
                app.ref_velocityEditField.Value = data.ref_velocity;			
                app.plot_sEditField.Value = data.plot_s;
                app.ref_centre_lonEditField_w.Value = data.ref_centre_lonlat_w(1);
                app.ref_centre_latEditField_w.Value = data.ref_centre_lonlat_w(2);
                app.ref_radiusEditField_w.Value = data.ref_radius_w;
                if data.ph_output == "unwrapped"   % output phase
                    app.unwrappedButton.Value = 1;
                    app.wrappedButton.Value = 0;
                elseif data.ph_output == "wrapped"
                    app.unwrappedButton.Value = 0;
                    app.wrappedButton.Value = 1;
                end

                drawnow;

                % Set the lamp color to green to indicate successful loading
                app.StatusLamp_3.Color = [0, 1, 0];  % Green      
                
            else
                % Handle the case when the file doesn't exist
                % Set the lamp color to red to indicate an error
                app.StatusLamp_3.Color = [1, 0, 0];  % Red
            end

        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            
            % Reset lamp colors to red
            app.StatusLamp.Color = [1, 0, 0]; % Red color for Save lamp
            app.StatusLamp_3.Color = [1, 0, 0]; % Red color for Load lamp

        end

        % Value changed function: TRAINatmosphericcorrectionCheckBox
        function TRAINatmosphericcorrectionCheckBoxValueChanged(app, event)
            value = app.TRAINatmosphericcorrectionCheckBox.Value;
            
            if value
                app.train_flag = 0;
            else
                app.train_flag = 1;
            end

        end

        % Value changed function: MasterdateDatePicker
        function MasterdateDatePickerValueChanged(app, event)
            value = app.MasterdateDatePicker.Value;
            
            date_txt = datetime(value, 'Format', 'ddMMyyyy');
            app.master_date = date_txt;

        end

        % Value changed function: DateDatePicker
        function DateDatePickerValueChanged(app, event)
            value = app.DateDatePicker.Value;
            
            app.day_0 = day(value);
            app.month_0 = month(value);
            app.year_0 = year(value);

        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            
            % Open a folder dialog for the user to select a folder
            selectedFolder = uigetdir('', 'Select a folder');
            
            % Check if the user selected a folder (clicked "OK" in the folder dialog)
            if isequal(selectedFolder, 0)
                % User canceled the folder selection, do nothing
            else
                % User selected a folder, update the EditField with the selected folder path
                app.PathEditField_2.Value = selectedFolder;
            end

        end

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)
            
            % Open a folder dialog for the user to select a folder
            selectedFolder = uigetdir('', 'Select a folder');
            
            % Check if the user selected a folder (clicked "OK" in the folder dialog)
            if isequal(selectedFolder, 0)
                % User canceled the folder selection, do nothing
            else
                % User selected a folder, update the EditField with the selected folder path
                app.PathEditField_3.Value = selectedFolder;
            end

        end

        % Value changed function: select_methodDropDown
        function select_methodDropDownValueChanged(app, event)
            value = app.select_methodDropDown.Value;
            
            % Check the selected value
            if strcmp(value, 'PERCENT')
                % Show the percent_randEditField and hide the density_randEditField
                app.percent_randEditField.Visible = 'on';
                app.percent_randEditFieldLabel.Visible = 'on';
                app.density_randEditField.Visible = 'off';
                app.density_randEditFieldLabel.Visible = 'off';
            elseif strcmp(value, 'DENSITY')
                % Show the density_randEditField and hide the percent_randEditField
                app.percent_randEditField.Visible = 'off';
                app.percent_randEditFieldLabel.Visible = 'off';
                app.density_randEditField.Visible = 'on';
                app.density_randEditFieldLabel.Visible = 'on';
            end

        end

        % Value changed function: datapreparationforStaMPSCheckBox
        function datapreparationforStaMPSCheckBoxValueChanged(app, event)
            value = app.datapreparationforStaMPSCheckBox.Value;
            
            if value
                app.stamps_preparation = 0;
            else
                app.stamps_preparation = 1;
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StaMPSPanel = uipanel(app.UIFigure);
            app.StaMPSPanel.Title = 'StaMPS';
            app.TabGroup = uitabgroup(app.StaMPSPanel);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.PreparationTab = uitab(app.TabGroup);
            app.PreparationTab.Title = 'Preparation';
            app.datapreparationforStaMPSCheckBox = uicheckbox(app.PreparationTab);
            app.datapreparationforStaMPSCheckBox.ValueChangedFcn = createCallbackFcn(app, @datapreparationforStaMPSCheckBoxValueChanged, true);
            app.datapreparationforStaMPSCheckBox.Text = 'data preparation for StaMPS';
            app.datapreparationforStaMPSCheckBox.Value = true;
            app.StaMPSinstallationfolderLabel = uilabel(app.PreparationTab);
            app.StaMPSinstallationfolderLabel.Text = 'StaMPS installation folder';
            app.PathEditField_2Label = uilabel(app.PreparationTab);
            app.PathEditField_2Label.Text = 'Path';
            app.PathEditField_2 = uieditfield(app.PreparationTab, 'text');
            app.ProjectfolderLabel = uilabel(app.PreparationTab);
            app.ProjectfolderLabel.Text = 'Project folder';
            app.PathEditField_3Label = uilabel(app.PreparationTab);
            app.PathEditField_3Label.Text = 'Path';
            app.PathEditField_3 = uieditfield(app.PreparationTab, 'text');
            app.MasterdateDatePicker_2Label = uilabel(app.PreparationTab);
            app.MasterdateDatePicker_2Label.Text = 'Master date';
            app.MasterdateDatePicker = uidatepicker(app.PreparationTab);
            app.MasterdateDatePicker.ValueChangedFcn = createCallbackFcn(app, @MasterdateDatePickerValueChanged, true);
            app.MasterdateDatePicker.Value = datetime([2020 3 5]);
            app.ValueEditFieldLabel = uilabel(app.PreparationTab);
            app.ValueEditFieldLabel.Text = 'Value';
            app.ValueEditField = uieditfield(app.PreparationTab, 'numeric');
            app.ValueEditField.Limits = [0 1];
            app.ValueEditField.ValueDisplayFormat = '%.2f';
            app.ValueEditField.Value = 0.4;
            app.AmplitudethresholdLabel = uilabel(app.PreparationTab);
            app.AmplitudethresholdLabel.Text = 'Amplitude threshold';
            app.BrowseButton = uibutton(app.PreparationTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.BrowseButton_2 = uibutton(app.PreparationTab, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Text = 'Browse';
            app.Image = uiimage(app.PreparationTab);
            app.Image2 = uiimage(app.PreparationTab);
            app.GlobalVariablesTab = uitab(app.TabGroup);
            app.GlobalVariablesTab.Title = 'Global Variables';
            app.InitialdateoftheanalyzedtimeseriesLabel = uilabel(app.GlobalVariablesTab);
            app.InitialdateoftheanalyzedtimeseriesLabel.Text = 'Initial date of the analyzed time series';
            app.ExportnamefortheoutputtablesxlsxandcsvLabel = uilabel(app.GlobalVariablesTab);
            app.ExportnamefortheoutputtablesxlsxandcsvLabel.Text = 'Export name for the output tables (.xlsx and .csv)';
            app.FilenameEditFieldLabel = uilabel(app.GlobalVariablesTab);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.GlobalVariablesTab, 'text');
            app.FilenameEditField.Value = 'filename';
            app.Label_8 = uilabel(app.GlobalVariablesTab);
            app.Label_8.Text = 'Dataset time span (automatically calculated from first to last acquisition)';
            app.DaysEditFieldLabel = uilabel(app.GlobalVariablesTab);
            app.DaysEditFieldLabel.Text = 'Days';
            app.DaysEditField = uieditfield(app.GlobalVariablesTab, 'numeric');
            app.DaysEditField.Limits = [0 Inf];
            app.DaysEditField.ValueDisplayFormat = '%.0f';
            app.DateDatePickerLabel = uilabel(app.GlobalVariablesTab);
            app.DateDatePickerLabel.Text = 'Date';
            app.DateDatePicker = uidatepicker(app.GlobalVariablesTab);
            app.DateDatePicker.DisplayFormat = 'dd.MM.uuuu';
            app.DateDatePicker.ValueChangedFcn = createCallbackFcn(app, @DateDatePickerValueChanged, true);
            app.DateDatePicker.Value = datetime([2019 7 9]);
            app.Label_9 = uilabel(app.GlobalVariablesTab);
            app.Label_9.Text = 'UTC acquisition time of the images (only needed for GACOS tropospheric correction - format hh:mm - only steps of 10 minutes)';
            app.TimeEditFieldLabel = uilabel(app.GlobalVariablesTab);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.GlobalVariablesTab, 'text');
            app.TimeEditField.Value = '00:00';
            app.ExportphaseButtonGroup = uibuttongroup(app.GlobalVariablesTab);
            app.ExportphaseButtonGroup.Title = 'Export phase';
            app.unwrappedButton = uiradiobutton(app.ExportphaseButtonGroup);
            app.unwrappedButton.Text = 'unwrapped';
            app.unwrappedButton.Value = true;
            app.wrappedButton = uiradiobutton(app.ExportphaseButtonGroup);
            app.wrappedButton.Text = 'wrapped';
            app.Image_2 = uiimage(app.GlobalVariablesTab);
            app.Image2_2 = uiimage(app.GlobalVariablesTab);
            app.StaMPS1Tab = uitab(app.TabGroup);
            app.StaMPS1Tab.Title = 'StaMPS 1';
            app.Step1LoaddataLabel = uilabel(app.StaMPS1Tab);
            app.Step1LoaddataLabel.Text = 'Step 1 - Load data';
            app.TRAINatmosphericcorrectionCheckBox = uicheckbox(app.StaMPS1Tab);
            app.TRAINatmosphericcorrectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @TRAINatmosphericcorrectionCheckBoxValueChanged, true);
            app.TRAINatmosphericcorrectionCheckBox.Text = 'TRAIN atmospheric correction';
            app.TRAINatmosphericcorrectionCheckBox.Value = true;
            app.StaMPSfirststepDropDownLabel = uilabel(app.StaMPS1Tab);
            app.StaMPSfirststepDropDownLabel.Text = 'StaMPS first step';
            app.StaMPSfirststepDropDown = uidropdown(app.StaMPS1Tab);
            app.StaMPSfirststepDropDown.Items = {'1', '2', '3', '4', '5', '6'};
            app.StaMPSfirststepDropDown.Value = '1';
            app.StaMPSlaststepDropDownLabel = uilabel(app.StaMPS1Tab);
            app.StaMPSlaststepDropDownLabel.Text = 'StaMPS last step';
            app.StaMPSlaststepDropDown = uidropdown(app.StaMPS1Tab);
            app.StaMPSlaststepDropDown.Items = {'7', '8'};
            app.StaMPSlaststepDropDown.Value = '7';
            app.NumberofcoresEditFieldLabel = uilabel(app.StaMPS1Tab);
            app.NumberofcoresEditFieldLabel.Text = 'Number of cores';
            app.NumberofcoresEditField = uieditfield(app.StaMPS1Tab, 'numeric');
            app.NumberofcoresEditField.Limits = [0 Inf];
            app.NumberofcoresEditField.RoundFractionalValues = 'on';
            app.NumberofcoresEditField.ValueDisplayFormat = '%.0f';
            app.NumberofcoresEditField.Value = 4;
            app.headingEditFieldLabel = uilabel(app.StaMPS1Tab);
            app.headingEditFieldLabel.Text = 'heading';
            app.headingEditField = uieditfield(app.StaMPS1Tab, 'numeric');
            app.headingEditField.Limits = [0 Inf];
            app.headingEditField.ValueDisplayFormat = '%.2f';
            app.headingEditField.Value = 346.18;
            app.degreeswithrespecttonorthLabel = uilabel(app.StaMPS1Tab);
            app.degreeswithrespecttonorthLabel.Text = '(degrees with respect to north)';
            app.lambdaEditFieldLabel = uilabel(app.StaMPS1Tab);
            app.lambdaEditFieldLabel.Text = 'lambda';
            app.lambdaEditField = uieditfield(app.StaMPS1Tab, 'numeric');
            app.lambdaEditField.Limits = [0 Inf];
            app.lambdaEditField.ValueDisplayFormat = '%.10f';
            app.lambdaEditField.Value = 0.055465763;
            app.wavelengthinmetersLabel = uilabel(app.StaMPS1Tab);
            app.wavelengthinmetersLabel.Text = '(wavelength in meters)';
            app.Image_3 = uiimage(app.StaMPS1Tab);
            app.Image2_3 = uiimage(app.StaMPS1Tab);
            app.StaMPS2Tab = uitab(app.TabGroup);
            app.StaMPS2Tab.Title = 'StaMPS 2';
            app.Step2EstimatePhaseNoiseLabel = uilabel(app.StaMPS2Tab);
            app.Step2EstimatePhaseNoiseLabel.Text = 'Step 2 -  Estimate Phase Noise';
            app.max_topo_errEditFieldLabel = uilabel(app.StaMPS2Tab);
            app.max_topo_errEditFieldLabel.Text = 'max_topo_err';
            app.max_topo_errEditField = uieditfield(app.StaMPS2Tab, 'numeric');
            app.max_topo_errEditField.Limits = [0 Inf];
            app.max_topo_errEditField.RoundFractionalValues = 'on';
            app.max_topo_errEditField.ValueDisplayFormat = '%.0f';
            app.max_topo_errEditField.Value = 16;
            app.filter_grid_sizeEditFieldLabel = uilabel(app.StaMPS2Tab);
            app.filter_grid_sizeEditFieldLabel.Text = 'filter_grid_size';
            app.filter_grid_sizeEditField = uieditfield(app.StaMPS2Tab, 'numeric');
            app.filter_grid_sizeEditField.Limits = [0 Inf];
            app.filter_grid_sizeEditField.RoundFractionalValues = 'on';
            app.filter_grid_sizeEditField.ValueDisplayFormat = '%.0f';
            app.filter_grid_sizeEditField.Value = 40;
            app.gamma_max_iterationsEditFieldLabel = uilabel(app.StaMPS2Tab);
            app.gamma_max_iterationsEditFieldLabel.Text = 'gamma_max_iterations';
            app.gamma_max_iterationsEditField = uieditfield(app.StaMPS2Tab, 'numeric');
            app.gamma_max_iterationsEditField.Limits = [0 Inf];
            app.gamma_max_iterationsEditField.RoundFractionalValues = 'on';
            app.gamma_max_iterationsEditField.ValueDisplayFormat = '%.0f';
            app.gamma_max_iterationsEditField.Value = 7;
            app.filter_weightingEditFieldLabel = uilabel(app.StaMPS2Tab);
            app.filter_weightingEditFieldLabel.Text = 'filter_weighting';
            app.filter_weightingEditField = uieditfield(app.StaMPS2Tab, 'text');
            app.filter_weightingEditField.Value = 'P-square';
            app.gamma_change_convergenceEditFieldLabel = uilabel(app.StaMPS2Tab);
            app.gamma_change_convergenceEditFieldLabel.Text = 'gamma_change_convergence';
            app.gamma_change_convergenceEditField = uieditfield(app.StaMPS2Tab, 'numeric');
            app.gamma_change_convergenceEditField.Limits = [0 Inf];
            app.gamma_change_convergenceEditField.ValueDisplayFormat = '%.4f';
            app.gamma_change_convergenceEditField.Value = 0.005;
            app.gamma_stdev_rejectEditFieldLabel = uilabel(app.StaMPS2Tab);
            app.gamma_stdev_rejectEditFieldLabel.Text = 'gamma_stdev_reject';
            app.gamma_stdev_rejectEditField = uieditfield(app.StaMPS2Tab, 'numeric');
            app.gamma_stdev_rejectEditField.Limits = [0 Inf];
            app.gamma_stdev_rejectEditField.ValueDisplayFormat = '%.2f';
            app.quick_est_gamma_flagEditFieldLabel = uilabel(app.StaMPS2Tab);
            app.quick_est_gamma_flagEditFieldLabel.Text = 'quick_est_gamma_flag';
            app.quick_est_gamma_flagEditField = uieditfield(app.StaMPS2Tab, 'text');
            app.quick_est_gamma_flagEditField.Value = 'y';
            app.small_baseline_flagEditFieldLabel = uilabel(app.StaMPS2Tab);
            app.small_baseline_flagEditFieldLabel.Text = 'small_baseline_flag';
            app.small_baseline_flagEditField = uieditfield(app.StaMPS2Tab, 'text');
            app.small_baseline_flagEditField.Value = 'n';
            app.clap_winEditFieldLabel = uilabel(app.StaMPS2Tab);
            app.clap_winEditFieldLabel.Text = 'clap_win';
            app.clap_winEditField = uieditfield(app.StaMPS2Tab, 'numeric');
            app.clap_winEditField.Limits = [0 Inf];
            app.clap_winEditField.RoundFractionalValues = 'on';
            app.clap_winEditField.ValueDisplayFormat = '%.0f';
            app.clap_winEditField.Value = 16;
            app.clap_alphaEditFieldLabel = uilabel(app.StaMPS2Tab);
            app.clap_alphaEditFieldLabel.Text = 'clap_alpha';
            app.clap_alphaEditField = uieditfield(app.StaMPS2Tab, 'numeric');
            app.clap_alphaEditField.Limits = [0 Inf];
            app.clap_alphaEditField.RoundFractionalValues = 'on';
            app.clap_alphaEditField.ValueDisplayFormat = '%.4f';
            app.clap_alphaEditField.Value = 1;
            app.clap_betaEditFieldLabel = uilabel(app.StaMPS2Tab);
            app.clap_betaEditFieldLabel.Text = 'clap_beta';
            app.clap_betaEditField = uieditfield(app.StaMPS2Tab, 'numeric');
            app.clap_betaEditField.Limits = [0 Inf];
            app.clap_betaEditField.ValueDisplayFormat = '%.4f';
            app.clap_betaEditField.Value = 0.3;
            app.clap_low_pass_wavelengthEditFieldLabel = uilabel(app.StaMPS2Tab);
            app.clap_low_pass_wavelengthEditFieldLabel.Text = 'clap_low_pass_wavelength';
            app.clap_low_pass_wavelengthEditField = uieditfield(app.StaMPS2Tab, 'numeric');
            app.clap_low_pass_wavelengthEditField.Limits = [0 Inf];
            app.clap_low_pass_wavelengthEditField.RoundFractionalValues = 'on';
            app.clap_low_pass_wavelengthEditField.ValueDisplayFormat = '%.0f';
            app.clap_low_pass_wavelengthEditField.Value = 800;
            app.maxuncorrelatedDEMerrormLabel = uilabel(app.StaMPS2Tab);
            app.maxuncorrelatedDEMerrormLabel.Text = '(max uncorrelated DEM error [m])';
            app.pixelsizeofresamplinggridLabel = uilabel(app.StaMPS2Tab);
            app.pixelsizeofresamplinggridLabel.Text = '(pixel size of resampling grid)';
            app.weightingschemeLabel = uilabel(app.StaMPS2Tab);
            app.weightingschemeLabel.Text = '(weighting scheme)';
            app.maximumnumberofiterationsLabel = uilabel(app.StaMPS2Tab);
            app.maximumnumberofiterationsLabel.Text = '(maximum number of iterations)';
            app.thresholdforchangeinmeanvalueofcoherenceLabel = uilabel(app.StaMPS2Tab);
            app.thresholdforchangeinmeanvalueofcoherenceLabel.Text = '(threshold for change in mean value of coherence)';
            app.quickestimationofgammacoherenceLabel = uilabel(app.StaMPS2Tab);
            app.quickestimationofgammacoherenceLabel.Text = '(quick estimation of gamma (coherence))';
            app.CLAPfilterwindowsizeLabel = uilabel(app.StaMPS2Tab);
            app.CLAPfilterwindowsizeLabel.Text = '(CLAP filter window size)';
            app.CLAPfilteralphatermLabel = uilabel(app.StaMPS2Tab);
            app.CLAPfilteralphatermLabel.Text = '(CLAP filter alpha term)';
            app.CLAPfilterbetatermLabel = uilabel(app.StaMPS2Tab);
            app.CLAPfilterbetatermLabel.Text = '(CLAP filter beta term)';
            app.CLAPfilterspatialwavelengthcutoffmLabel = uilabel(app.StaMPS2Tab);
            app.CLAPfilterspatialwavelengthcutoffmLabel.Text = '(CLAP filter spatial wavelength cutoff [m])';
            app.Image_4 = uiimage(app.StaMPS2Tab);
            app.Image2_4 = uiimage(app.StaMPS2Tab);
            app.StaMPS3Tab = uitab(app.TabGroup);
            app.StaMPS3Tab.Title = 'StaMPS 3';
            app.Step3PSselectionLabel = uilabel(app.StaMPS3Tab);
            app.Step3PSselectionLabel.Text = 'Step 3 -  PS selection';
            app.percent_randEditFieldLabel = uilabel(app.StaMPS3Tab);
            app.percent_randEditFieldLabel.Text = 'percent_rand';
            app.percent_randEditField = uieditfield(app.StaMPS3Tab, 'numeric');
            app.percent_randEditField.Limits = [0 100];
            app.percent_randEditField.RoundFractionalValues = 'on';
            app.percent_randEditField.ValueDisplayFormat = '%.0f';
            app.percent_randEditField.Value = 1;
            app.density_randEditFieldLabel = uilabel(app.StaMPS3Tab);
            app.density_randEditFieldLabel.Text = 'density_rand';
            app.density_randEditField = uieditfield(app.StaMPS3Tab, 'numeric');
            app.density_randEditField.Limits = [0 Inf];
            app.density_randEditField.RoundFractionalValues = 'on';
            app.density_randEditField.ValueDisplayFormat = '%.0f';
            app.density_randEditField.Value = 25;
            app.select_methodDropDownLabel = uilabel(app.StaMPS3Tab);
            app.select_methodDropDownLabel.Text = 'select_method';
            app.select_methodDropDown = uidropdown(app.StaMPS3Tab);
            app.select_methodDropDown.Items = {'PERCENT', 'DENSITY'};
            app.select_methodDropDown.ValueChangedFcn = createCallbackFcn(app, @select_methodDropDownValueChanged, true);
            app.select_methodDropDown.Value = 'PERCENT';
            app.Step4PSweedingLabel = uilabel(app.StaMPS3Tab);
            app.Step4PSweedingLabel.Text = 'Step 4 -  PS weeding';
            app.weed_time_winEditFieldLabel = uilabel(app.StaMPS3Tab);
            app.weed_time_winEditFieldLabel.Text = 'weed_time_win';
            app.weed_time_winEditField = uieditfield(app.StaMPS3Tab, 'numeric');
            app.weed_time_winEditField.Limits = [0 Inf];
            app.weed_time_winEditField.RoundFractionalValues = 'on';
            app.weed_time_winEditField.ValueDisplayFormat = '%.0f';
            app.weed_time_winEditField.Value = 730;
            app.weed_time_winDescriptionLabel = uilabel(app.StaMPS3Tab);
            app.weed_time_winDescriptionLabel.Text = '(temporal smoothing window, days)';
            app.weed_standard_devEditFieldLabel = uilabel(app.StaMPS3Tab);
            app.weed_standard_devEditFieldLabel.Text = 'weed_standard_dev';
            app.weed_standard_devEditField = uieditfield(app.StaMPS3Tab, 'numeric');
            app.weed_standard_devEditField.Limits = [0 100];
            app.weed_standard_devEditField.RoundFractionalValues = 'on';
            app.weed_standard_devEditField.ValueDisplayFormat = '%.0f';
            app.weed_standard_devEditField.Value = 1;
            app.weed_neighboursEditFieldLabel = uilabel(app.StaMPS3Tab);
            app.weed_neighboursEditFieldLabel.Text = 'weed_neighbours';
            app.weed_neighboursEditField = uieditfield(app.StaMPS3Tab, 'text');
            app.weed_neighboursEditField.Value = 'y';
            app.weed_zero_elevationEditFieldLabel = uilabel(app.StaMPS3Tab);
            app.weed_zero_elevationEditFieldLabel.Text = 'weed_zero_elevation';
            app.weed_zero_elevationEditField = uieditfield(app.StaMPS3Tab, 'text');
            app.weed_zero_elevationEditField.Value = 'n';
            app.Step5PhasecorrectionLabel = uilabel(app.StaMPS3Tab);
            app.Step5PhasecorrectionLabel.Text = 'Step 5 -  Phase correction';
            app.merge_resample_sizeEditFieldLabel = uilabel(app.StaMPS3Tab);
            app.merge_resample_sizeEditFieldLabel.Text = 'merge_resample_size';
            app.merge_resample_sizeEditField = uieditfield(app.StaMPS3Tab, 'numeric');
            app.merge_resample_sizeEditField.Limits = [0 Inf];
            app.merge_resample_sizeEditField.RoundFractionalValues = 'on';
            app.merge_resample_sizeEditField.ValueDisplayFormat = '%.0f';
            app.merge_standard_devEditFieldLabel = uilabel(app.StaMPS3Tab);
            app.merge_standard_devEditFieldLabel.Text = 'merge_standard_dev';
            app.merge_standard_devEditField = uieditfield(app.StaMPS3Tab, 'numeric');
            app.merge_standard_devEditField.Limits = [0 Inf];
            app.merge_standard_devEditField.ValueDisplayFormat = '%.0f';
            app.merge_standard_devEditField.Value = Inf;
            app.weed_max_noiseEditFieldLabel = uilabel(app.StaMPS3Tab);
            app.weed_max_noiseEditFieldLabel.Text = 'weed_max_noise';
            app.weed_max_noiseEditField = uieditfield(app.StaMPS3Tab, 'numeric');
            app.weed_max_noiseEditField.Limits = [0 Inf];
            app.weed_max_noiseEditField.ValueDisplayFormat = '%.0f';
            app.weed_max_noiseEditField.Value = Inf;
            app.selectionmethodforpixelswithrandomphaseLabel = uilabel(app.StaMPS3Tab);
            app.selectionmethodforpixelswithrandomphaseLabel.Text = '(selection method for pixels with random phase)';
            app.maximumacceptablepercentagedenistyLabel = uilabel(app.StaMPS3Tab);
            app.maximumacceptablepercentagedenistyLabel.Text = '(maximum acceptable percentage/denisty)';
            app.thresholdstandarddeviationLabel = uilabel(app.StaMPS3Tab);
            app.thresholdstandarddeviationLabel.Text = '(threshold standard deviation)';
            app.flagforproximityweedingLabel = uilabel(app.StaMPS3Tab);
            app.flagforproximityweedingLabel.Text = '(flag for proximity weeding)';
            app.flagforzeroelevationweedingLabel = uilabel(app.StaMPS3Tab);
            app.flagforzeroelevationweedingLabel.Text = '(flag for zero elevation weeding)';
            app.thresholdforthemaximumnoiseallowedforapixelLabel = uilabel(app.StaMPS3Tab);
            app.thresholdforthemaximumnoiseallowedforapixelLabel.Text = '(threshold for the maximum noise allowed for a pixel)';
            app.coarserpostingtoresampletom0noresamplingisappliedLabel = uilabel(app.StaMPS3Tab);
            app.coarserpostingtoresampletom0noresamplingisappliedLabel.Text = '(coarser posting to resample to [m], 0 = no resampling is applied)';
            app.thresholdstandarddeviationforresamplingLabel = uilabel(app.StaMPS3Tab);
            app.thresholdstandarddeviationforresamplingLabel.Text = '(threshold standard deviation for resampling)';
            app.Image_5 = uiimage(app.StaMPS3Tab);
            app.Image2_5 = uiimage(app.StaMPS3Tab);
            app.StaMPS4Tab = uitab(app.TabGroup);
            app.StaMPS4Tab.Title = 'StaMPS 4';
            app.Step6PhaseunwrappingLabel = uilabel(app.StaMPS4Tab);
            app.Step6PhaseunwrappingLabel.Text = 'Step 6 -  Phase unwrapping';
            app.unwrap_time_winEditFieldLabel = uilabel(app.StaMPS4Tab);
            app.unwrap_time_winEditFieldLabel.Text = 'unwrap_time_win';
            app.unwrap_time_winEditField = uieditfield(app.StaMPS4Tab, 'numeric');
            app.unwrap_time_winEditField.Limits = [0 Inf];
            app.unwrap_time_winEditField.RoundFractionalValues = 'on';
            app.unwrap_time_winEditField.ValueDisplayFormat = '%.0f';
            app.unwrap_time_winEditField.Value = 730;
            app.unwrap_time_winDescriptionLabel = uilabel(app.StaMPS4Tab);
            app.unwrap_time_winDescriptionLabel.Text = '(phase-noise smoothing window, days)';
            app.unwrap_grid_sizeEditFieldLabel = uilabel(app.StaMPS4Tab);
            app.unwrap_grid_sizeEditFieldLabel.Text = 'unwrap_grid_size';
            app.unwrap_grid_sizeEditField = uieditfield(app.StaMPS4Tab, 'numeric');
            app.unwrap_grid_sizeEditField.Limits = [0 Inf];
            app.unwrap_grid_sizeEditField.RoundFractionalValues = 'on';
            app.unwrap_grid_sizeEditField.ValueDisplayFormat = '%.0f';
            app.unwrap_grid_sizeEditField.Value = 20;
            app.unwrap_gold_n_winEditFieldLabel = uilabel(app.StaMPS4Tab);
            app.unwrap_gold_n_winEditFieldLabel.Text = 'unwrap_gold_n_win';
            app.unwrap_gold_n_winEditField = uieditfield(app.StaMPS4Tab, 'numeric');
            app.unwrap_gold_n_winEditField.Limits = [0 Inf];
            app.unwrap_gold_n_winEditField.RoundFractionalValues = 'on';
            app.unwrap_gold_n_winEditField.ValueDisplayFormat = '%.0f';
            app.unwrap_gold_n_winEditField.Value = 16;
            app.unwrap_methodEditFieldLabel = uilabel(app.StaMPS4Tab);
            app.unwrap_methodEditFieldLabel.Text = 'unwrap_method';
            app.unwrap_methodEditField = uieditfield(app.StaMPS4Tab, 'text');
            app.unwrap_methodEditField.Value = '3D';
            app.unwrap_gold_alphaEditFieldLabel = uilabel(app.StaMPS4Tab);
            app.unwrap_gold_alphaEditFieldLabel.Text = 'unwrap_gold_alpha';
            app.unwrap_gold_alphaEditField = uieditfield(app.StaMPS4Tab, 'numeric');
            app.unwrap_gold_alphaEditField.Limits = [0 Inf];
            app.unwrap_gold_alphaEditField.ValueDisplayFormat = '%.2f';
            app.unwrap_gold_alphaEditField.Value = 0.8;
            app.unwrap_alphaEditFieldLabel = uilabel(app.StaMPS4Tab);
            app.unwrap_alphaEditFieldLabel.Text = 'unwrap_alpha';
            app.unwrap_alphaEditField = uieditfield(app.StaMPS4Tab, 'numeric');
            app.unwrap_alphaEditField.Limits = [0 Inf];
            app.unwrap_alphaEditField.ValueDisplayFormat = '%.0f';
            app.unwrap_alphaEditField.Value = 8;
            app.unwrap_spatial_cost_func_flagEditFieldLabel = uilabel(app.StaMPS4Tab);
            app.unwrap_spatial_cost_func_flagEditFieldLabel.Text = 'unwrap_spatial_cost_func_flag';
            app.unwrap_spatial_cost_func_flagEditField = uieditfield(app.StaMPS4Tab, 'text');
            app.unwrap_spatial_cost_func_flagEditField.Value = 'n';
            app.unwrap_prefilter_flagEditFieldLabel = uilabel(app.StaMPS4Tab);
            app.unwrap_prefilter_flagEditFieldLabel.Text = 'unwrap_prefilter_flag';
            app.unwrap_prefilter_flagEditField = uieditfield(app.StaMPS4Tab, 'text');
            app.unwrap_prefilter_flagEditField.Value = 'y';
            app.unwrap_patch_phaseEditFieldLabel = uilabel(app.StaMPS4Tab);
            app.unwrap_patch_phaseEditFieldLabel.Text = 'unwrap_patch_phase';
            app.unwrap_patch_phaseEditField = uieditfield(app.StaMPS4Tab, 'text');
            app.unwrap_patch_phaseEditField.Value = 'n';
            app.unwrap_la_error_flagEditFieldLabel = uilabel(app.StaMPS4Tab);
            app.unwrap_la_error_flagEditFieldLabel.Text = 'unwrap_la_error_flag';
            app.unwrap_la_error_flagEditField = uieditfield(app.StaMPS4Tab, 'text');
            app.unwrap_la_error_flagEditField.Value = 'y';
            app.unwrap_hold_good_valuesEditFieldLabel = uilabel(app.StaMPS4Tab);
            app.unwrap_hold_good_valuesEditFieldLabel.Text = 'unwrap_hold_good_values';
            app.unwrap_hold_good_valuesEditField = uieditfield(app.StaMPS4Tab, 'text');
            app.unwrap_hold_good_valuesEditField.Value = 'n';
            app.subtr_tropoEditFieldLabel = uilabel(app.StaMPS4Tab);
            app.subtr_tropoEditFieldLabel.Text = 'subtr_tropo';
            app.subtr_tropoEditField = uieditfield(app.StaMPS4Tab, 'text');
            app.subtr_tropoEditField.Value = 'y';
            app.tropo_methodEditFieldLabel = uilabel(app.StaMPS4Tab);
            app.tropo_methodEditFieldLabel.Text = 'tropo_method';
            app.tropo_methodEditField = uieditfield(app.StaMPS4Tab, 'text');
            app.tropo_methodEditField.Value = 'a_gacos';
            app.select_reest_gamma_flagEditFieldLabel = uilabel(app.StaMPS4Tab);
            app.select_reest_gamma_flagEditFieldLabel.Text = 'select_reest_gamma_flag';
            app.select_reest_gamma_flagEditField = uieditfield(app.StaMPS4Tab, 'text');
            app.select_reest_gamma_flagEditField.Value = 'y';
            app.drop_ifg_indexEditFieldLabel = uilabel(app.StaMPS4Tab);
            app.drop_ifg_indexEditFieldLabel.Text = 'drop_ifg_index';
            app.drop_ifg_indexEditField = uieditfield(app.StaMPS4Tab, 'text');
            app.drop_ifg_indexEditField.Value = '[]';
            app.resamplinggridspacingLabel = uilabel(app.StaMPS4Tab);
            app.resamplinggridspacingLabel.Text = '(resampling grid spacing)';
            app.windowsizeforGoldsteinfilterLabel = uilabel(app.StaMPS4Tab);
            app.windowsizeforGoldsteinfilterLabel.Text = '(window size for Goldstein filter)';
            app.unwrappingmethodLabel = uilabel(app.StaMPS4Tab);
            app.unwrappingmethodLabel.Text = '(unwrapping method)';
            app.valueofalphaforGoldsteinfilterLabel = uilabel(app.StaMPS4Tab);
            app.valueofalphaforGoldsteinfilterLabel.Text = '(value of alpha for Goldstein filter)';
            app.prefilterphasebeforeunwrappingtoreducenoiseLabel = uilabel(app.StaMPS4Tab);
            app.prefilterphasebeforeunwrappingtoreducenoiseLabel.Text = '(prefilter phase before unwrapping to reduce noise)';
            app.usethepatchphaseofstep3asprefilteredphaseLabel = uilabel(app.StaMPS4Tab);
            app.usethepatchphaseofstep3asprefilteredphaseLabel.Text = '(use the patch phase of step 3 as prefiltered phase)';
            app.flagforlookangleLabel = uilabel(app.StaMPS4Tab);
            app.flagforlookangleLabel.Text = '(flag for look angle)';
            app.subtracttroposphericeffectTRAINLabel = uilabel(app.StaMPS4Tab);
            app.subtracttroposphericeffectTRAINLabel.Text = '(subtract tropospheric effect (TRAIN))';
            app.methodfortroposphericcorrectionTRAINLabel = uilabel(app.StaMPS4Tab);
            app.methodfortroposphericcorrectionTRAINLabel.Text = '(method for tropospheric correction (TRAIN). Supported options are a_linear, a_gacos)';
            app.removeselectedinterferogramsmeansnoalternativelyie13Label = uilabel(app.StaMPS4Tab);
            app.removeselectedinterferogramsmeansnoalternativelyie13Label.Text = '(remove selected interferograms, [] means no, alternatively i.e. [1 3] )';
            app.Image_6 = uiimage(app.StaMPS4Tab);
            app.Image2_6 = uiimage(app.StaMPS4Tab);
            app.StaMPS5Tab = uitab(app.TabGroup);
            app.StaMPS5Tab.Title = 'StaMPS 5';
            app.Step7EstimatespatiallycorrelatedlookangleerrorLabel = uilabel(app.StaMPS5Tab);
            app.Step7EstimatespatiallycorrelatedlookangleerrorLabel.Text = 'Step 7 -  Estimate spatially correlated look angle error';
            app.scla_derampEditFieldLabel = uilabel(app.StaMPS5Tab);
            app.scla_derampEditFieldLabel.Text = 'scla_deramp';
            app.scla_derampEditField = uieditfield(app.StaMPS5Tab, 'text');
            app.scla_derampEditField.Value = 'y';
            app.scla_methodEditFieldLabel = uilabel(app.StaMPS5Tab);
            app.scla_methodEditFieldLabel.Text = 'scla_method';
            app.scla_methodEditField = uieditfield(app.StaMPS5Tab, 'text');
            app.scla_methodEditField.Value = 'L2';
            app.scla_drop_indexEditFieldLabel = uilabel(app.StaMPS5Tab);
            app.scla_drop_indexEditFieldLabel.Text = 'scla_drop_index';
            app.scla_drop_indexEditField = uieditfield(app.StaMPS5Tab, 'text');
            app.scla_drop_indexEditField.Value = '[]';
            app.Step8AtmosphericfilteringLabel = uilabel(app.StaMPS5Tab);
            app.Step8AtmosphericfilteringLabel.Text = 'Step 8 -  Atmospheric filtering';
            app.scn_time_winEditFieldLabel = uilabel(app.StaMPS5Tab);
            app.scn_time_winEditFieldLabel.Text = 'scn_time_win';
            app.scn_time_winEditField = uieditfield(app.StaMPS5Tab, 'numeric');
            app.scn_time_winEditField.Limits = [0 Inf];
            app.scn_time_winEditField.RoundFractionalValues = 'on';
            app.scn_time_winEditField.ValueDisplayFormat = '%.0f';
            app.scn_time_winEditField.Value = 365;
            app.scn_time_winDescriptionLabel = uilabel(app.StaMPS5Tab);
            app.scn_time_winDescriptionLabel.Text = '(temporal filter window, days)';
            app.scn_kriging_flagEditFieldLabel = uilabel(app.StaMPS5Tab);
            app.scn_kriging_flagEditFieldLabel.Text = 'scn_kriging_flag';
            app.scn_kriging_flagEditField = uieditfield(app.StaMPS5Tab, 'text');
            app.scn_kriging_flagEditField.Value = 'n';
            app.scn_wavelengthEditFieldLabel = uilabel(app.StaMPS5Tab);
            app.scn_wavelengthEditFieldLabel.Text = 'scn_wavelength';
            app.scn_wavelengthEditField = uieditfield(app.StaMPS5Tab, 'numeric');
            app.scn_wavelengthEditField.Limits = [0 Inf];
            app.scn_wavelengthEditField.RoundFractionalValues = 'on';
            app.scn_wavelengthEditField.ValueDisplayFormat = '%.0f';
            app.scn_wavelengthEditField.Value = 50;
            app.flagforphaserampestimationLabel = uilabel(app.StaMPS5Tab);
            app.flagforphaserampestimationLabel.Text = '(flag for phase ramp estimation)';
            app.sclamethodLabel = uilabel(app.StaMPS5Tab);
            app.sclamethodLabel.Text = '(scla method)';
            app.Label_10 = uilabel(app.StaMPS5Tab);
            app.Label_10.Text = '(remove selected interferograms from step 7 & 8, [] means no, alternatively i.e. [1 3])';
            app.spatialfilterLabel = uilabel(app.StaMPS5Tab);
            app.spatialfilterLabel.Text = '(spatial filter)';
            app.Image_7 = uiimage(app.StaMPS5Tab);
            app.Image2_7 = uiimage(app.StaMPS5Tab);
            app.StaMPSAOITab = uitab(app.TabGroup);
            app.StaMPSAOITab.Title = 'StaMPS AOI';
            app.PlotsandAOIsettingsUNWRAPPEDPHASELabel = uilabel(app.StaMPSAOITab);
            app.PlotsandAOIsettingsUNWRAPPEDPHASELabel.Text = 'Plots and AOI settings (UNWRAPPED PHASE)';
            app.ref_centre_lonEditFieldLabel = uilabel(app.StaMPSAOITab);
            app.ref_centre_lonEditFieldLabel.Text = 'ref_centre_lon';
            app.ref_centre_lonEditField = uieditfield(app.StaMPSAOITab, 'numeric');
            app.ref_centre_lonEditField.Limits = [-180 180];
            app.ref_centre_lonEditField.ValueDisplayFormat = '%.3f';
            app.ref_centre_latEditFieldLabel = uilabel(app.StaMPSAOITab);
            app.ref_centre_latEditFieldLabel.Text = 'ref_centre_lat';
            app.ref_centre_latEditField = uieditfield(app.StaMPSAOITab, 'numeric');
            app.ref_centre_latEditField.Limits = [-90 90];
            app.ref_centre_latEditField.ValueDisplayFormat = '%.3f';
            app.ref_radiusEditFieldLabel = uilabel(app.StaMPSAOITab);
            app.ref_radiusEditFieldLabel.Text = 'ref_radius';
            app.ref_radiusEditField = uieditfield(app.StaMPSAOITab, 'numeric');
            app.ref_radiusEditField.Limits = [0 Inf];
            app.ref_radiusEditField.RoundFractionalValues = 'on';
            app.ref_radiusEditField.ValueDisplayFormat = '%.0f';
            app.ref_velocityEditFieldLabel = uilabel(app.StaMPSAOITab);
            app.ref_velocityEditFieldLabel.Text = 'ref_velocity';
            app.ref_velocityEditField = uieditfield(app.StaMPSAOITab, 'numeric');
            app.ref_velocityEditField.ValueDisplayFormat = '%.3f';
            app.plot_sEditFieldLabel = uilabel(app.StaMPSAOITab);
            app.plot_sEditFieldLabel.Text = 'plot_s';
            app.plot_sEditField = uieditfield(app.StaMPSAOITab, 'numeric');
            app.plot_sEditField.Limits = [0 Inf];
            app.plot_sEditField.RoundFractionalValues = 'on';
            app.plot_sEditField.ValueDisplayFormat = '%.0f';
            app.plot_sEditField.Value = 15;
            app.longitudedegcoordinatesoftheunwrappingspatialreferenceLabel = uilabel(app.StaMPSAOITab);
            app.longitudedegcoordinatesoftheunwrappingspatialreferenceLabel.Text = '(longitude deg coordinates of the unwrapping spatial reference)';
            app.latitudedegcoordinatesoftheunwrappingspatialreferenceLabel = uilabel(app.StaMPSAOITab);
            app.latitudedegcoordinatesoftheunwrappingspatialreferenceLabel.Text = '(latitude deg coordinates of the unwrapping spatial reference)';
            app.radiusforPSselectionmLabel = uilabel(app.StaMPSAOITab);
            app.radiusforPSselectionmLabel.Text = '(radius for PS selection [m])';
            app.referencevelocityofunwrappingreferenceLabel = uilabel(app.StaMPSAOITab);
            app.referencevelocityofunwrappingreferenceLabel.Text = '(reference velocity of unwrapping reference)';
            app.dimensionofPSpixelsintheplotLabel = uilabel(app.StaMPSAOITab);
            app.dimensionofPSpixelsintheplotLabel.Text = '(dimension of PS pixels in the plot)';
            app.PlotsandAOIsettingsWRAPPEDPHASELabel = uilabel(app.StaMPSAOITab);
            app.PlotsandAOIsettingsWRAPPEDPHASELabel.Text = 'Plots and AOI settings (WRAPPED PHASE)';
            app.longitudedegcoordinatesoftheunwrappingspatialreferenceLabel_w = uilabel(app.StaMPSAOITab);
            app.longitudedegcoordinatesoftheunwrappingspatialreferenceLabel_w.Text = '(longitude deg coordinates of the export central point)';
            app.latitudedegcoordinatesoftheunwrappingspatialreferenceLabel_w = uilabel(app.StaMPSAOITab);
            app.latitudedegcoordinatesoftheunwrappingspatialreferenceLabel_w.Text = '(latitude deg coordinates of the export central point)';
            app.radiusforPSselectionmLabel_w = uilabel(app.StaMPSAOITab);
            app.radiusforPSselectionmLabel_w.Text = '(radius for PS selection [m])';
            app.ref_centre_lonEditField_2Label = uilabel(app.StaMPSAOITab);
            app.ref_centre_lonEditField_2Label.Text = 'ref_centre_lon';
            app.ref_centre_lonEditField_w = uieditfield(app.StaMPSAOITab, 'numeric');
            app.ref_centre_lonEditField_w.Limits = [-180 180];
            app.ref_centre_lonEditField_w.ValueDisplayFormat = '%.3f';
            app.ref_centre_latEditField_2Label = uilabel(app.StaMPSAOITab);
            app.ref_centre_latEditField_2Label.Text = 'ref_centre_lat';
            app.ref_centre_latEditField_w = uieditfield(app.StaMPSAOITab, 'numeric');
            app.ref_centre_latEditField_w.Limits = [-90 90];
            app.ref_centre_latEditField_w.ValueDisplayFormat = '%.3f';
            app.ref_radiusEditField_2Label = uilabel(app.StaMPSAOITab);
            app.ref_radiusEditField_2Label.Text = 'ref_radius';
            app.ref_radiusEditField_w = uieditfield(app.StaMPSAOITab, 'numeric');
            app.ref_radiusEditField_w.Limits = [0 Inf];
            app.ref_radiusEditField_w.RoundFractionalValues = 'on';
            app.ref_radiusEditField_w.ValueDisplayFormat = '%.0f';
            app.Image_8 = uiimage(app.StaMPSAOITab);
            app.Image2_8 = uiimage(app.StaMPSAOITab);
            app.SaveLoadTab = uitab(app.TabGroup);
            app.SaveLoadTab.Title = 'Save/Load';
            app.SaveButton = uibutton(app.SaveLoadTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SavetheconfiguredparametersfortheInSARpreprocessingLabel = uilabel(app.SaveLoadTab);
            app.SavetheconfiguredparametersfortheInSARpreprocessingLabel.Text = 'Save the configured parameters for the InSAR pre-processing';
            app.StatusLampLabel = uilabel(app.SaveLoadTab);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.SaveLoadTab);
            app.SavetheconfiguredparametersfortheInSARpreprocessingLabel_3 = uilabel(app.SaveLoadTab);
            app.SavetheconfiguredparametersfortheInSARpreprocessingLabel_3.Text = 'Load the parameters for the InSAR pre-processing from the already existing .mat file';
            app.LoadButton = uibutton(app.SaveLoadTab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.StatusLamp_3Label = uilabel(app.SaveLoadTab);
            app.StatusLamp_3Label.Text = 'Status';
            app.StatusLamp_3 = uilamp(app.SaveLoadTab);
            app.Image_9 = uiimage(app.SaveLoadTab);
            app.Image2_9 = uiimage(app.SaveLoadTab);
            app.RunTab = uitab(app.TabGroup);
            app.RunTab.Title = 'Run';
            app.Label_6 = uilabel(app.RunTab);
            app.Label_6.Text = 'Press the button below to start the pre-processing of the SAR images for the PS analysis';
            app.StartButton = uibutton(app.RunTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.Label_7 = uilabel(app.RunTab);
            app.Label_7.Text = 'Execution outputs:';
            app.PreprocessingstatusLampLabel = uilabel(app.RunTab);
            app.PreprocessingstatusLampLabel.Text = 'Preprocessing status';
            app.PreprocessingstatusLamp = uilamp(app.RunTab);
            app.MessagesTextArea_3Label = uilabel(app.RunTab);
            app.MessagesTextArea_3Label.Text = 'Messages';
            app.MessagesTextArea = uitextarea(app.RunTab);
            app.Image_10 = uiimage(app.RunTab);
            app.Image2_10 = uiimage(app.RunTab);
            app.TSPointsTab = uitab(app.TabGroup);
            app.TSPointsTab.Title = 'TS Points';
            % Welcome content (removed and replaced by the embedded
            % picker once OpenTSPickerButton is pushed).
            tsLabel = uilabel(app.TSPointsTab);
            tsLabel.FontName = 'Manrope';
            tsLabel.FontWeight = 'bold';
            tsLabel.FontSize = 14;
            tsLabel.Position = [40 572 1100 22];
            tsLabel.Text = ['Pick query points (manually or by clicking on the velocity map) ' ...
                            'and export their time series as CSV.'];
            tsLabel2 = uilabel(app.TSPointsTab);
            tsLabel2.FontName = 'Manrope';
            tsLabel2.Position = [40 540 1100 22];
            tsLabel2.Text = ['Requires stamps(7) + ps_plot(''v-do'',''ts'',1,...) to have produced ' ...
                             'ps_plot_ts_v-do.mat in the project folder set on the Preparation tab.'];
            tsLabel3 = uilabel(app.TSPointsTab);
            tsLabel3.FontName = 'Manrope';
            tsLabel3.FontAngle = 'italic';
            tsLabel3.Position = [40 510 1100 22];
            tsLabel3.Text = ['If <project folder>/aoi_points.csv already exists, the picker auto-loads it on start.'];
            % Create OpenTSPickerButton — clicking it embeds the picker
            % into THIS tab (no popup window).
            app.OpenTSPickerButton = uibutton(app.TSPointsTab, 'push');
            app.OpenTSPickerButton.ButtonPushedFcn = createCallbackFcn(app, @OpenTSPickerButtonPushed, true);
            app.OpenTSPickerButton.Text = 'Load TS picker into this tab';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end

        % WARNING: weed_time_win, unwrap_time_win and scn_time_win GUI
        % controls are added by tools/apply_stamps_time_windows.py, which
        % patches matlab/document.xml only, NOT appdesigner/appModel.mat.
        % Re-saving this app from App Designer may strip them. Re-run the
        % patcher to restore them.

        % WARNING: TS Points tab + OpenTSPickerButton + TSPointsTab
        % property + this callback are added by tools/add_tspoints_tab.py
        % which patches matlab/document.xml only, NOT
        % appdesigner/appModel.mat. Re-saving this app from App Designer
        % may strip them. Re-run the patcher to restore.
        % See docs/TS_export_picker_integration.md.
        %
        % Button pushed function: OpenTSPickerButton
        % Embeds the time-series picker INSIDE app.TSPointsTab — the
        % welcome labels and the button itself are wiped (the picker
        % calls delete(parent.Children)).
        function OpenTSPickerButtonPushed(app, event)
            % The TS .mat lives where ps_plot is invoked (which is the
            % stamps folder = parent of PATCH_1 = location of the .mlapp).
            % NOT the project folder (= PHASE root): that is the input
            % side, used by StartButtonPushed to build s2s_export_path.
            workdir = fileparts(mfilename('fullpath'));
            if isempty(workdir) || ~isfolder(workdir)
                uialert(app.UIFigure, ...
                    'Cannot resolve stamps folder (mfilename returned empty).', ...
                    'Workdir missing');
                return;
            end

            % Auto-generate ps_plot_ts_<value_type>.mat if missing. The
            % picker reads (ph_mm, lonlat, day) per PS from it. Upstream
            % StaMPS expects the user to run ps_plot('v-do','ts',1,...)
            % by hand after stamps(7); for the embedded picker UX we do
            % it transparently.
            value_type = 'v-do';
            mat_path = fullfile(workdir, ['ps_plot_ts_' value_type '.mat']);
            if ~isfile(mat_path)
                prev_pwd = pwd;
                ok = false;
                err_msg = '';
                figs_before = findall(0, 'Type', 'figure');
                try
                    cd(workdir);
                    % ps_plot's 'ts' flag triggers ts_flaghelper which
                    % saves the matfile as a side-effect of the velocity
                    % plot. Suppress its stdout via evalc; ignore any new
                    % figures it opens (the picker takes over the tab).
                    % NB: pass ONLY (value_type, 'ts', BACKGROUND=1). Extra
                    % positional numeric args after 'ts' are consumed by
                    % ps_plot as PHASE_LIMS/REF_IFG/IFG_LIST/... — IFG_LIST
                    % collapses ph_mm to a single column and the picker
                    % then rejects the .mat as 'contains only one
                    % distinct epoch'.
                    evalc("ps_plot('" + value_type + "','ts',1)");
                    ok = isfile(mat_path);
                catch ME
                    err_msg = ME.message;
                end
                figs_after = findall(0, 'Type', 'figure');
                new_figs = setdiff(figs_after, figs_before);
                for fk = 1:length(new_figs)
                    try
                        if isvalid(new_figs(fk)) && new_figs(fk) ~= app.UIFigure
                            delete(new_figs(fk));
                        end
                    catch
                    end
                end
                cd(prev_pwd);
                if ~ok
                    if isempty(err_msg)
                        err_msg = sprintf(['ps_plot finished but %s was not produced. ' ...
                                           'Run stamps(7) first, then retry.'], mat_path);
                    end
                    uialert(app.UIFigure, err_msg, 'TS data not available');
                    return;
                end
            end

            % Pass the user-chosen Filename so per-point CSVs land
            % alongside the legacy STEP 3 all-PS export in EXPORT/
            % with names <Filename>_<id>.csv. Empty Filename falls
            % back to ts_export/ts_<id>.csv inside the picker.
            export_name = '';
            try
                if isprop(app, 'FilenameEditField') && ...
                        ~isempty(app.FilenameEditField.Value)
                    export_name = char(app.FilenameEditField.Value);
                end
            catch
                export_name = '';
            end

            try
                ts_export_picker(workdir, app.TSPointsTab, value_type, export_name);
            catch ME
                uialert(app.UIFigure, ME.message, 'Picker failed');
            end
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PHASE_StaMPS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end